classdef atpbatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ATPBatlab                       matlab.ui.Figure
        SaveinputsessionMenu            matlab.ui.container.Menu
        LoadinputsessionMenu            matlab.ui.container.Menu
        ParseATPfileMenu                matlab.ui.container.Menu
        RunMenu                         matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ParametersTab                   matlab.ui.container.Tab
        ATPheaderPanel                  matlab.ui.container.Panel
        TmaxsEditField                  matlab.ui.control.NumericEditField
        TmaxsEditFieldLabel             matlab.ui.control.Label
        dTsEditField                    matlab.ui.control.NumericEditField
        deltaTsEditFieldLabel           matlab.ui.control.Label
        XoptEditField                   matlab.ui.control.NumericEditField
        XoptEditFieldLabel              matlab.ui.control.Label
        CoptEditField                   matlab.ui.control.NumericEditField
        CoptEditFieldLabel              matlab.ui.control.Label
        ModelvariablesandvaluerangesLabel  matlab.ui.control.Label
        row2Delete                      matlab.ui.control.EditField
        DeleteselectedrowButton         matlab.ui.control.Button
        AddrowButton                    matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        EditCheckBox_Inc                matlab.ui.control.CheckBox
        INCLUDEINSERTTextArea           matlab.ui.control.TextArea
        INCLUDEINSERTTextAreaLabel      matlab.ui.control.Label
        ProjectdescriptionTextArea      matlab.ui.control.TextArea
        ProjectdescriptionTextAreaLabel  matlab.ui.control.Label
        JobIDEditField                  matlab.ui.control.EditField
        JobIDEditFieldLabel             matlab.ui.control.Label
        Load2TableButton                matlab.ui.control.Button
        SourcefileatpEditField          matlab.ui.control.TextArea
        SourcefileatpTextAreaLabel      matlab.ui.control.Label
        LoadFileButton                  matlab.ui.control.Button
        RefactorTab                     matlab.ui.container.Tab
        RedefineSWITCHoutputsLabel      matlab.ui.control.Label
        UITable_4                       matlab.ui.control.Table
        RedefineBRANCHoutputsLabel      matlab.ui.control.Label
        UITable_3                       matlab.ui.control.Table
        row2Delete_2                    matlab.ui.control.EditField
        DeleteselectedrowButton_2       matlab.ui.control.Button
        AddrowButton_2                  matlab.ui.control.Button
        ConditionalvariablesLabel       matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        TasksTab                        matlab.ui.container.Tab
        UseWineLinuxCheckBox            matlab.ui.control.CheckBox
        FilesinJOBIDTextArea            matlab.ui.control.TextArea
        FilesinJOBIDTextAreaLabel       matlab.ui.control.Label
        PathstoexecutablesandoutputfolderPanel  matlab.ui.container.Panel
        MATLABbinaryEditField           matlab.ui.control.EditField
        MATLABbinaryEditFieldLabel      matlab.ui.control.Label
        LoadMATLABButton                matlab.ui.control.Button
        PL42MATEditField                matlab.ui.control.EditField
        PL42MATEditFieldLabel           matlab.ui.control.Label
        LoadPl42matButton               matlab.ui.control.Button
        WorkdirectoryEditField          matlab.ui.control.EditField
        WorkdirectoryEditFieldLabel     matlab.ui.control.Label
        LoadWorkdirButton               matlab.ui.control.Button
        ATPsolverEditField              matlab.ui.control.EditField
        ATPsolverEditFieldLabel         matlab.ui.control.Label
        LoadATPButton                   matlab.ui.control.Button
        PostprocessingButtonGroup       matlab.ui.container.ButtonGroup
        ConverttoCOMTRADECheckBox       matlab.ui.control.CheckBox
        ConverttoMATLABCheckBox         matlab.ui.control.CheckBox
        CleanupPL4filesCheckBox         matlab.ui.control.CheckBox
        LaunchdebugButton               matlab.ui.control.Button
        ClearmessagesButton             matlab.ui.control.Button
        ProgrammessagesTextArea         matlab.ui.control.TextArea
        ConsolemessagesTextAreaLabel    matlab.ui.control.Label
        TasksButtonGroup                matlab.ui.container.ButtonGroup
        PostprocessfilesCheckBox        matlab.ui.control.CheckBox
        RunATPsolverCheckBox            matlab.ui.control.CheckBox
        BuildcasefilesCheckBox          matlab.ui.control.CheckBox
        SolvercallButtonGroup           matlab.ui.container.ButtonGroup
        BuildRunBatButton               matlab.ui.control.RadioButton
        BuildbatfileButton              matlab.ui.control.RadioButton
        ContextMenu                     matlab.ui.container.ContextMenu
        SendtoConditionalvariablesMenu  matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        SendtoModelvariablesMenu        matlab.ui.container.Menu
    end


    methods (Access = private)

        function [out] = uiquestdlg(app,msg,title,opts,defaultopt,cancelopt)
                h_fig = findall(0,'Name','ATP-Batlab');
                out=uiconfirm(h_fig,msg,title,'Options',opts,'DefaultOption',defaultopt,'CancelOption',cancelopt);
        end

        function [out] = uimsgbox(app,msg,title,icon)
                h_fig = findall(0,'Name','ATP-Batlab');
                out = h_fig;
                uialert(h_fig,msg,title,'Icon',icon);
        end

        function [isgood, msg] = validateFilename(app)
            fname=string(app.SourcefileatpEditField.Value);
            isgood=true;
            msg='';
            if  ~exist(fname, 'file')
                msg='Invalid file name!';
                isgood=false;
                return;
            end
        end


        function [results, msg] = loadFromFile(app,fname)
            results=[];
            msg=[];
            try
                fid=fopen(fname);
                data=textscan(fid, '%s', 'delimiter', '\n','whitespace', '');
                fclose(fid);
                data=data{1,1};
            catch
                msg='File contains invalid data!';
                try;fclose(fid);catch;end;
                return;
            end
            idx = find(contains(data,'C  dT  >< Tmax >< Xopt >< Copt ><Epsiln>'));
            tmp_str=string(data(idx(1)+1));
            tmp_str=split(tmp_str,' ');
            tmp_str(tmp_str=="")=[];
            results.dT=tmp_str(1);
            results.Tmax=tmp_str(2);

            if length(tmp_str)==4
                results.Xopt=tmp_str(3);
                results.Copt=tmp_str(4);
            else
                results.Xopt="0";
                results.Copt="0";
            end
            
            inc_names={};
            idx = find(contains(data,'$INCLUDE'));
            idx = [idx; find(contains(data,'$INSERT'))];
            for k=1:length(idx)
                tmp_str=string(data(idx(k)));
                tmp_str=split(tmp_str,',');
                inc_names{k,1}=char(strtrim(tmp_str(2)));
            end
            results.include=unique(inc_names);

            idx = find(contains(data,'$PARAMETER'));
            c=0;
            if ~isempty(idx)
                for k = idx(1)+1:1:idx(end)-1
                    tmp_str=string(data(k));
                    tmp_str=split(tmp_str,'=');
                    if contains(tmp_str(2),'$$')
                        % found a primary variable
                        c=c+1;
                        varnames{c,1}=strtrim(tmp_str(1));
                        tmp_str2=split(tmp_str(2),'$$');
                        varvalues{c,1}=strtrim(tmp_str2(1));
                        vartype{c,1}='numeric';
                    elseif contains(tmp_str(2),'''')
                        % found a string variable
                        c=c+1;
                        varnames{c,1}=strtrim(tmp_str(1));
                        varvalues{c,1}=strtrim(tmp_str(2));
                        vartype{c,1}='string';
                    end
                end
                results.params=[varnames vartype varvalues varvalues];
            end

            idx_in = find(contains(data,'/BRANCH'));
            idx_en = find(contains(data,'/SWITCH'));
            if isempty(idx_en);idx_en = find(contains(data,'/SOURCE'));end
            if isempty(idx_en);idx_en = find(contains(data,'/OUTPUT'));end
            if isempty(idx_en);idx_en = find(contains(data,'BLANK BRANCH'));end
            c=0;
            line=[];
            fromstr=[];
            tostr=[];
            outputstr=[];
            if ~isempty(idx_in)
                for k = idx_in(1)+1:1:idx_en(end)-1
                    tmp_char=char(data(k));
                    testflag=(tmp_char(end-1));
                    outputflag=str2double(tmp_char(end));
                    if ~isnan(outputflag) && length(tmp_char)==80 && tmp_char(1)~='C' && testflag==' ' && outputflag <=4
                        c=c+1;
                        line{c,1}=string(k);
                        fromstr{c,1}=string(tmp_char(3:8));
                        tostr{c,1}=string(tmp_char(9:14));
                        switch outputflag
                            case 0
                                outputstr{c,1}='0 - No';
                            case 1
                                outputstr{c,1}='1 - Current';
                            case 2
                                outputstr{c,1}='2 - Voltage';
                            case 3
                                outputstr{c,1}='3 - Current&Voltage';
                            case 4
                                outputstr{c,1}='4 - Power&Energy';

                        end

                    end
                end
                results.branchoutputs=[line fromstr tostr outputstr];
            end

            idx_in = find(contains(data,'/SWITCH'));
            idx_en = find(contains(data,'/SOURCE'));
            if isempty(idx_en);idx_en = find(contains(data,'/OUTPUT'));end
            if isempty(idx_en);idx_en = find(contains(data,'BLANK SWITCH'));end
            c=0;
            line=[];
            fromstr=[];
            tostr=[];
            outputstr=[];
            if ~isempty(idx_in)
                for k = idx_in(1)+1:1:idx_en(end)-1
                    tmp_char=char(data(k));
                    outputflag=str2double(tmp_char(end));
                    if ~isnan(outputflag) && length(tmp_char)==80 && tmp_char(1)~='C'
                        c=c+1;
                        line{c,1}=string(k);
                        fromstr{c,1}=tmp_char(3:8);
                        tostr{c,1}=tmp_char(9:14);
                        switch outputflag
                            case 0
                                outputstr{c,1}='0 - No';
                            case 1
                                outputstr{c,1}='1 - Current';
                            case 2
                                outputstr{c,1}='2 - Voltage';
                            case 3
                                outputstr{c,1}='3 - Current&Voltage';
                            case 4
                                outputstr{c,1}='4 - Power&Energy';
                        end

                    end
                end
                results.switchoutputs=[line fromstr tostr outputstr];
            end

        end


        function out = redefRowLabels(app)
            nrows = size(app.UITable.Data,1);
            app.UITable.RowName=string(1:nrows);

            nrows = size(app.UITable_2.Data,1);
            app.UITable_2.RowName=string(1:nrows);

            nrows = size(app.UITable_3.Data,1);
            app.UITable_3.RowName=string(1:nrows);

            nrows = size(app.UITable_4.Data,1);
            app.UITable_4.RowName=string(1:nrows);

        end

        function results = saveAllData(app)

            results=false;
            % [testdir,msg]=validateWorkDir(app);
            % if ~testdir
            %    f = msgbox(msg, 'You lose, fella.','error');
            %    return;
            % end

            [testfile,msg]=validateProjID(app);
            if ~testfile
                f = uimsgbox(app, msg, 'You lose, fella.','error');
                return;
            end

            %ok now do stuff
            projID=app.JobIDEditField.Value;
            % f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [~,wdir] = uiputfile('*.mat','Save input',[projID '.mat']);
            % delete(f); %delete the dummy figure

            %             wdir=app.WorkingdirectoryEditField.Value;
            if wdir ~= 0
                projdir=fullfile(wdir);
                %             orig_state = warning;warning('off','all');
                %             mkdir(projdir); warning(orig_state);
                %             mkdir(projdir);
                fname=[projID '.mat'];
                atpbatlab_data = readAllAppValues(app);
                save(fullfile(projdir,fname),'atpbatlab_data');
                %             warning(orig_state);
                results=true;
            end
        end

        function results = writeAllAppValues(app,atpbatlab_data)

            props = properties(app);

            for iprop = 1:length(props)
                thisprop = props{iprop};

                if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                        strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                        strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                        strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable')


                    if isprop(app.(thisprop),'Value')
                        app.(thisprop).Value=atpbatlab_data.(thisprop).Value;
                    end

                    app.(thisprop).Enable=atpbatlab_data.(thisprop).Enable;
                    app.(thisprop).Visible=atpbatlab_data.(thisprop).Visible;

                    if isprop(app.(thisprop),'Editable')
                        app.(thisprop).Editable=atpbatlab_data.(thisprop).Editable;
                    end

                    if isprop(app.(thisprop),'List')
                        app.(thisprop).List=atpbatlab_data.(thisprop).List;
                    end

                    if isprop(app.(thisprop),'Data')
                        app.(thisprop).Data=atpbatlab_data.(thisprop).Data;
                    end

                end

            end
        end

        function appdata = readAllAppValues(app)
            appdata=struct;

            props = properties(app);

            for iprop = 1:length(props)
                thisprop = props{iprop};

                if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                        strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                        strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                        strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable')

                    appdata.(thisprop)=struct;

                    if isprop(app.(thisprop),'Value')
                        appdata.(thisprop).Value=app.(thisprop).Value;
                    end

                    appdata.(thisprop).Enable=app.(thisprop).Enable;
                    appdata.(thisprop).Visible=app.(thisprop).Visible;

                    if isprop(app.(thisprop),'Editable')
                        appdata.(thisprop).Editable=app.(thisprop).Editable;
                    end

                    if isprop(app.(thisprop),'List')
                        appdata.(thisprop).List=app.(thisprop).List;
                    end

                    if isprop(app.(thisprop),'Data')
                        appdata.(thisprop).Data=app.(thisprop).Data;
                    end

                end

            end
        end

        function [isgood, msg] = validateProjID(app)
            projID=app.JobIDEditField.Value;
            isgood=true;
            msg='';
            BadChar = '<>:"/\|?*';
            BadName = {'CON', 'PRN', 'AUX', 'NUL', 'CLOCK$', ...
                'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', ...
                'COM7', 'COM8', 'COM9', ...
                'LPT1', 'LPT2', 'LPT3', 'LPT4', 'LPT5', 'LPT6', ...
                'LPT7', 'LPT8', 'LPT9'};
            if isempty(projID)
                isgood=false;
                msg='You must specify a project ID!';
                return;
            else
                bad = ismember(BadChar, projID);
                if any(bad)
                    msg = ['Project ID contains bad characters: ', BadChar(bad)];
                    isgood=false;
                    return;
                elseif any(projID < 32)
                    msg = ['Project ID  contains non printable characters, ASCII:', sprintf(' %d', projID(projID < 32))];
                    isgood=false;
                    return;
                elseif ~isempty(projID) && (projID(end) == ' ' || projID(end) == '.')
                    msg = 'Project ID must not contain a trailing space or dot.';
                    isgood=false;
                    return;
                else
                    [~, name] = fileparts(projID);
                    if any(strcmpi(name, BadName))
                        msg = ['Project ID not allowed: ', name];
                        isgood=false;
                        return;
                    end
                end
            end
        end

        function [vars, vartable] = buildCaseData(app)
            numvars=size(app.UITable.Data,1);
            str_allcomb='allcomb(';

            for k=1:numvars
                vars(k).param=app.UITable.Data.PARAM(k);
                vars(k).type=app.UITable.Data.Type(k);
                tmp_eval=eval(app.UITable.Data.NewValues(k));
                if iscell(tmp_eval)
                    vars(k).data=tmp_eval;
                elseif isnumeric(tmp_eval)
                    vars(k).data=num2cell(tmp_eval);
                elseif ischar(tmp_eval)
                    vars(k).data={tmp_eval};
                end
                str_allcomb=[str_allcomb sprintf('vars(%d).data,',k)];
            end
            str_allcomb=[str_allcomb(1:end-1) ')'];
            vartable=eval(str_allcomb);

        end


        function [outfile] = writeATPCaseFiles(app,~,jobid,vars,allcases_data)
            outfile=[];
            workdir=app.WorkdirectoryEditField.Value;
            pl4bin=app.PL42MATEditField.Value;


            infile=fullfile(app.SourcefileatpEditField.Value);
            infile=infile{1}; %it seems that using fullfile breaks the input god knows why

            templatedir=fileparts(infile);
            purgepl4=app.CleanupPL4filesCheckBox.Value;
            keepmatfiles=app.ConverttoMATLABCheckBox.Value;
            try
                fid=fopen(infile);
                data=textscan(fid, '%s', 'Delimiter', '\n', 'CollectOutput', true, 'Whitespace', '');
                fclose(fid);
                data=data{1,1};

                numvars=size(vars,2);
                numcases=size(allcases_data,1);
                app.ProgrammessagesTextArea.Value{end+1} = ['****** Building ATP files...'];
                
                %write ATP hedaer for all files
                head{1,1}='C ------------------------------------------------------------------------------';
                head{end+1,1}='C Case file generated using ATP-Batlab';
                head{end+1,1}='C ATP-Batlab developed by A. G. Martins-Britto - 2024';
                head{end+1,1}='C github.com/amaurigmartins/ATPBatlab';
                head{end+1,1}='C ';
                head{end+1,1}=sprintf('C JobID: %s',app.JobIDEditField.Value);
                if ~isempty(app.ProjectdescriptionTextArea.Value{1})
                    head{end+1,1}=sprintf('C Project description:');
                    head{end+1,1}=sprintf('C -------------------');
                    for k=1:size(app.ProjectdescriptionTextArea.Value,1)
                        if ~isempty(app.ProjectdescriptionTextArea.Value{k})
                            head{end+1,1}=sprintf('C %s',app.ProjectdescriptionTextArea.Value{k});
                        end
                    end
                end
                head{end+1,1}='C ------------------------------------------------------------------------------';

                for j=1:numcases
                    thiscasedata=allcases_data(j,:);
                    app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Now working case #%d.',j)];
                    for k=1:numvars
                        idx = find(startsWith(data,[char(vars(k).param) ' =']));
                        if vars(k).type == 'numeric'
                            data{idx} = [char(vars(k).param) ' =' sprintf('%1.4E',allcases_data{j,k}) ' $$'];
                        elseif vars(k).type == 'string'
                            data{idx} = [char(vars(k).param) ' =' sprintf('''%s''',allcases_data{j,k})];
                        end
                    end

                    % ah, the conditional $PARAMETERS - the ultimate PITA that I
                    %brought upon myself because I cannot keep quiet
                    numel_condparam=height(app.UITable_2.Data);
                    for k=1:numel_condparam
                        thisparam=app.UITable_2.Data.PARAM(k);
                        idx = find(startsWith(data,[char(thisparam) ' =']));
                        condition=app.UITable_2.Data.Trigger(k);
                        pattern2 = '\{([^}]*)\}';
                        matches = regexp(condition, pattern2, 'tokens');
                        currentVars=[vars(:).param];
                        c=1;
                        if ~isempty(matches)
                            for i = 1:length(matches)
                                extractedString = matches{i}{1}; % Extract the string from the cell array
                                var_idx=find(strcmp(extractedString,currentVars));
                                if ~isempty(var_idx)
                                    varIndices(c)=var_idx;
                                    varValues(c)=allcases_data{j,var_idx};
                                    c=c+1;
                                end
                                condition=strrep(condition,['{' extractedString '}'],num2str(allcases_data{j,var_idx}));
                            end
                        end
                        thisValueTrue=app.UITable_2.Data.NewValue(k);
                        matches = regexp(thisValueTrue, pattern2, 'tokens');
                        if ~isempty(matches)
                            var_idx=find(strcmp(matches{1}{1},currentVars));
                            thisValueTrue=strrep(thisValueTrue,['{' matches{1}{1} '}'],num2str(allcases_data{j,var_idx}));
                        end


                        thisValueFalse=app.UITable_2.Data.TriggerValue(k);
                        matches = regexp(thisValueFalse, pattern2, 'tokens');
                        if ~isempty(matches)
                            var_idx=find(strcmp(matches{1}{1},currentVars));
                            thisValueFalse=strrep(thisValueFalse,['{' matches{1}{1} '}'],num2str(allcases_data{j,var_idx}));
                        end

                        if eval(condition)
                            if ~isnan(str2double(thisValueTrue))
                                data{idx} = [char(thisparam) ' =' sprintf('%1.4E',thisValueTrue) ' $$'];
                            else
                                data{idx} = [char(thisparam) ' =' sprintf('''%s''',thisValueTrue)];
                            end
                        else
                            if ~isnan(str2double(thisValueFalse))
                                data{idx} = [char(thisparam) ' =' sprintf('%1.4E',thisValueFalse) ' $$'];
                            else
                                data{idx} = [char(thisparam) ' =' sprintf('''%s''',thisValueFalse)];
                            end
                        end
                    end

                    idx = find(contains(data,'C  dT  >< Tmax >< Xopt >< Copt ><Epsiln>'));
                    dT=app.dTsEditField.Value;
                    Tmax=app.TmaxsEditField.Value;
                    Copt=app.CoptEditField.Value;
                    Xopt=app.XoptEditField.Value;
                    tmp_str=blanks(80);

                    tmp_str(1:8)= sprintf('% 8.8G',dT);
                    tmp_str(9:16)=sprintf('% 8.2f',Tmax);
                    if ~strcmp(string(app.XoptEditField.Value),'0')
                        tmp_str(17:24)=sprintf('% 8.8G',Xopt);
                    end
                    if ~strcmp(string(app.CoptEditField.Value),'0')
                        tmp_str(25:32)=sprintf('% 8.8G',Copt);
                    end
                    data{idx+1}=tmp_str;
                    
                    %let us handle the branch refactoring
                    numel_branch=height(app.UITable_3.Data);
                    for k=1:numel_branch
                        idx = str2double(app.UITable_3.Data.Line(k));
                        if ~isempty(idx)
                            tmp_str=data{idx};
                            if length(tmp_str)==80
                                curr_outputflag=tmp_str(80);
                                new_outputflag=char(app.UITable_3.Data.Output(k));
                                new_outputflag=new_outputflag(1);
                                if  ~isnan(str2double(curr_outputflag))
                                    if curr_outputflag~=new_outputflag
                                        tmp_str(80)=new_outputflag;
                                        data{idx}=tmp_str;
                                    end
                                end
                            end
                        end
                    end

                    %now the switch section
                    numel_switch=height(app.UITable_4.Data);
                    for k=1:numel_switch
                        idx = str2double(app.UITable_4.Data.Line(k));
                        if ~isempty(idx)
                            tmp_str=data{idx};
                            if length(tmp_str)==80
                                curr_outputflag=tmp_str(80);
                                new_outputflag=char(app.UITable_4.Data.Output(k));
                                new_outputflag=new_outputflag(1);
                                if  ~isnan(str2double(curr_outputflag))
                                    if curr_outputflag~=new_outputflag
                                        tmp_str(80)=new_outputflag;
                                        data{idx}=tmp_str;
                                    end
                                end
                            end
                        end
                    end

                    out=strjoin([head; data], '\n') ;%para escrever o arquivo
                    out=strrep(out,'\','\\');
                    out=strrep(out,'%','%%');
                    %         out = regexprep(out, {'\r', '\n\n+'}, {'', '\n'});
                    %         out=strcat(out,'\n');
                    outfile = cellfun(@num2str,allcases_data(j,:),'UniformOutput',false);
                    outfile = strjoin(outfile,'_');
                    outfile = strrep(outfile,'.','_');
                    matexp = sprintf('.c%d_%s_postprocdata.mat',j,outfile);
                    outfile = sprintf('c%d_%s.atp',j,outfile);
                    filelist{j}=outfile;


                    fid = fopen(fullfile(workdir,jobid,outfile),'wt');
                    fprintf(fid, out);
                    fclose(fid);

                    save(fullfile(workdir,jobid,matexp),'pl4bin','workdir',...
                        'jobid','outfile','vars','thiscasedata',...
                        'templatedir','purgepl4','keepmatfiles');

                end
                app.ProgrammessagesTextArea.Value{end+1} = ['Building cases index...'];
                C = [filelist' allcases_data];
                T = cell2table(C, "VariableNames",["Filename" [vars.param]]);
                indexfname = fullfile(workdir,jobid,'CasesIndex.xlsx');
                writetable(T,indexfname);
                app.ProgrammessagesTextArea.Value{end+1} = ['All done!'];


            catch ME
                msg=sprintf('Unable to write output file! Check the console for details.');
                f = uimsgbox(app,msg,'You lose, fella.','error');
                app.ProgrammessagesTextArea.Value{end+1} = ['Something went wrong (and no, I am not very good with making descriptive messages).'];
                app.ProgrammessagesTextArea.Value{end+1} = ['According to some experts, it''s very likely that you tried to use a $PARAMETER name that does not exist in the original ATP file.'];
                app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Anyway, here''s the MATLAB exception stack in case you know what you are doing:\nIdentifier: %s\nMessage: %s\n',ME.identifier,ME.message)];
                for k=1:numel(ME.stack)
                    app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Name:%s\nLine: %d',ME.stack(k).name,ME.stack(k).line)];
                end
                try;fclose(fid);catch;end;
                return;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.ConfigTab.Parent=[];
            app.EditCheckBox_Inc.Value=0;
            app.INCLUDEINSERTTextArea.Editable=0;
            orig_state = warning;warning('off','all');
            currentFolder = pwd;
            app.WorkdirectoryEditField.Value=pwd;
            addpath(fullfile(currentFolder,'functions'));
            warning(orig_state);
            t=table;
            t.PARAM=strings(0);
            t.Type=strings(0);
            t.CurrentValue=strings(0);
            t.NewValues=strings(0);
            %             t.Step=zeros(0);
            %             t.Max=zeros(0);
            %             t.Preffix=strings(0);

            t.Type = categorical(t.Type,{'numeric','string'}, ...
                'Ordinal',true);

            app.UITable.Data=t;
            app.UITable.ColumnEditable = [true true true true];
            app.UITable.ColumnWidth = {'auto',120,90,80};

            t=table;
            t.PARAM=strings(0);
            t.Trigger=strings(0);
            t.TriggerValue=strings(0);
            t.NewValue=strings(0);
            % t.Trigger = categorical(t.Trigger,{'equals','does not equal', 'contains', 'does not contain', 'is greater than', 'is greater than or equal to', 'is less than', 'is less than or equal to'}, ...
            %     'Ordinal',true);


            app.UITable_2.Data=t;
            app.UITable_2.ColumnEditable = [true true true true];
            app.UITable_2.ColumnWidth = {120,'auto',90,80};

            t=table;
            t.Line=strings(0);
            t.From=strings(0);
            t.To=strings(0);
            t.Output=strings(0);
            t.Output = categorical(t.Output,{'0 - No','1 - Current', '2 - Voltage', '3 - Current&Voltage', '4 - Power&Energy'}, ...
                'Ordinal',true);
            app.UITable_3.Data=t;
            app.UITable_3.ColumnEditable = [false false false true];
            app.UITable_3.ColumnWidth = {40,70,70,110};

            app.UITable_4.Data=t;
            app.UITable_4.ColumnEditable = [false false false true];
            app.UITable_4.ColumnWidth = {40,70,70,110};


        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)

            [file,path] = uigetfile({'*.atp'});
            % app.ATPBatlab.Visible = 'off';
            % app.ATPBatlab.Visible = 'on';
            if file ~=0
                fname=fullfile(path,file);
                app.SourcefileatpEditField.Value=fname;
                Load2TableButtonPushed(app, event);
            end
        end

        % Callback function: Load2TableButton, ParseATPfileMenu
        function Load2TableButtonPushed(app, event)
            [testfile,msg]=validateFilename(app);
            if ~testfile
                f = uimsgbox(app,msg, 'You lose, fella.','error');
                return;
            end
            fname=string(app.SourcefileatpEditField.Value);
            
            startupFcn(app)

            [fdata,msg]=loadFromFile(app,fname);

            if isempty(fdata)
                f = uimsgbox(app,msg, 'You lose, fella.','error');
                return;
            else
                app.dTsEditField.Value=str2double(fdata.dT);
                app.CoptEditField.Value=str2double(fdata.Copt);
                app.XoptEditField.Value=str2double(fdata.Xopt);
                app.TmaxsEditField.Value=str2double(fdata.Tmax);
                nrows=size(fdata.params,1);
                if ~isempty(fdata.include)
                    app.INCLUDEINSERTTextArea.Value=fdata.include;
                end

                app.UITable.Data=[app.UITable.Data;fdata.params];
                % if isempty(app.UITable_3.Data)
                %     app.UITable_3.Data=fdata.branchoutputs;
                % else
                    app.UITable_3.Data=[app.UITable_3.Data;fdata.branchoutputs];
                % end
                % if isempty(app.UITable_4.Data)
                %     app.UITable_4.Data=fdata.switchoutputs;
                % else
                    app.UITable_4.Data=[app.UITable_4.Data;fdata.switchoutputs];
                % end
                % app.ATPBatlab.Visible = 'off';
                % app.ATPBatlab.Visible = 'on';
                f = uimsgbox(app,sprintf('Found %d valid $PARAMETERS!',nrows), 'Success','success');
                redefRowLabels(app)
                drawnow()
            end

        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            newrow={'VAR','numeric',9999,9999};
            app.UITable.Data=[app.UITable.Data;newrow];
            redefRowLabels(app)


        end

        % Button pushed function: DeleteselectedrowButton
        function DeleteselectedrowButtonPushed(app, event)
            delrow=str2double(app.row2Delete.Value);
            if ~isempty(app.UITable.Data)
                if isnan(delrow)
                    app.UITable.Data(end,:)=[];
                else
                    app.UITable.Data(delrow,:)=[];
                end
                redefRowLabels(app)
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if ~isempty(app.UITable.Data)
                indices = event.Indices;
                if ~isempty(indices)
                    selectedRow = indices(1);
                    app.row2Delete.Value=string(selectedRow);
                end

            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(1,2)==4
                try
                    test=eval(newData);
                catch
                    f = uimsgbox(app,'Invalid data or MATLAB operator.','You lose, fella.','error');
                    app.UITable.Data.NewValues(indices(1))='';
                    return;
                end
            end
        end

        % Button pushed function: LoadATPButton
        function LoadATPButtonPushed(app, event)
            [file,path] = uigetfile({'*.exe';'*.bat'});
            % app.ATPBatlab.Visible = 'off';
            % app.ATPBatlab.Visible = 'on';
            if path ~=0
                app.ATPsolverEditField.Value=fullfile(path,file);
            end
        end

        % Button pushed function: LoadWorkdirButton
        function LoadWorkdirButtonPushed(app, event)
            path=uigetdir(pwd,'Choose work directory');
            % app.ATPBatlab.Visible = 'off';
            % app.ATPBatlab.Visible = 'on';
            if path ~=0
                app.WorkdirectoryEditField.Value=fullfile(path);
            end
        end

        % Button pushed function: LaunchdebugButton
        function LaunchdebugButtonPushed(app, event)
            debugme
        end

        % Button pushed function: ClearmessagesButton
        function ClearmessagesButtonPushed(app, event)
            app.ProgrammessagesTextArea.Value='';
        end

        % Button pushed function: LoadMATLABButton
        function LoadMATLABButtonPushed(app, event)
            [file,path] = uigetfile({'*.exe'});
            % app.ATPBatlab.Visible = 'off';
            % app.ATPBatlab.Visible = 'on';
            if path ~=0
                app.MATLABbinaryEditField.Value=fullfile(path,file);
            end
        end

        % Button pushed function: LoadPl42matButton
        function LoadPl42matButtonPushed(app, event)
            [file,path] = uigetfile({'*.exe'});
            % app.ATPBatlab.Visible = 'off';
            % app.ATPBatlab.Visible = 'on';
            if path ~=0
                app.PL42MATEditField.Value=fullfile(path,file);
            end
        end

        % Value changed function: EditCheckBox_Inc
        function EditCheckBox_IncValueChanged(app, event)
            value = app.EditCheckBox_Inc.Value;
            app.INCLUDEINSERTTextArea.Editable=value;
        end

        % Menu selected function: SaveinputsessionMenu
        function SaveinputsessionMenuSelected(app, event)
            saveAllData(app);
            app.ATPBatlab.Visible = 'off';
            app.ATPBatlab.Visible = 'on';
        end

        % Menu selected function: LoadinputsessionMenu
        function LoadinputsessionMenuSelected(app, event)
             [file,path] = uigetfile('*.mat');
            % app.ATPBatlab.Visible = 'off';
            % app.ATPBatlab.Visible = 'on';
            if file ~= 0
                fname=fullfile(path,file);
                try
                    load(fname,'atpbatlab_data');
                    writeAllAppValues(app,atpbatlab_data);
                    redefRowLabels(app);
                catch
                    f = uimsgbox(app,'Unable to load project file!','You lose, fella.','error');
                    return;
                end
            end
        end

        % Menu selected function: RunMenu
        function RunMenuSelected(app, event)
            nonechecked=true;
            jobid=app.JobIDEditField.Value;

            infile=fullfile(app.SourcefileatpEditField.Value);


            workdir=app.WorkdirectoryEditField.Value;
            solvercall=app.ATPsolverEditField.Value;
            pl42matcall=app.PL42MATEditField.Value;
            matlabcall=app.MATLABbinaryEditField.Value;


            fsearch=split(string(app.FilesinJOBIDTextArea.Value),';');
            basedir=fullfile(app.WorkdirectoryEditField.Value,app.JobIDEditField.Value);
            allfiles=[];
            for i=1:numel(fsearch)
                allfiles=[allfiles; dir(fullfile(basedir,'**',strtrim(fsearch(i))))];
            end
            if ~isempty(allfiles)
                answer = uiquestdlg(app,sprintf('Output files were already found inside the JobID folder. If you proceed with this action, all existing output files ***WILL BE DESTROYED!*** \n\n Are you REALLY SURE that you want to continue? '), ...
                    '\WARNING\WARNING\WARNING\WARNING\WARNING\', ...
                    ["Yes","No"],1,2);
                if strcmp(answer,'No')
                    return;
                end
            end

            try
                clc;
                if app.BuildcasefilesCheckBox.Value == 1
                    nonechecked=false;
                    if ~isempty(app.UITable.Data)
                        [vars, vartable] = buildCaseData(app);
                        if isempty(app.ProgrammessagesTextArea.Value{1})
                            app.ProgrammessagesTextArea.Value{1} = ['****** Process started.'];
                        else
                            app.ProgrammessagesTextArea.Value{end+1} = ['****** Process started.'];
                        end
                        orig_state = warning;warning('off','all');
                        if isfolder(fullfile(workdir,jobid))

                            if ~isempty(allfiles)
                                answer = uiquestdlg(app,sprintf('THIS IS YOUR FINAL WARNING! ALL EXISTING DATA WILL BE DELETED! \n\n Confirm again: '), ...
                                    '\WARNING\WARNING\WARNING\WARNING\WARNING\', ...
                                    ["Yes","No"],1,2);
                                if strcmp(answer,'No')
                                    return;
                                else
                                    rmdir(fullfile(workdir,jobid), 's');
                                end
                            end


                        end
                        mkdir(fullfile(workdir,jobid));
                        warning(orig_state);

                        if ~isempty(app.INCLUDEINSERTTextArea.Value{1})
                            app.ProgrammessagesTextArea.Value{end+1} = ['Copying linked files...'];

                            infile=fullfile(app.SourcefileatpEditField.Value);


                            [filepath,~,~] = fileparts(infile);
                            for k=1:size(app.INCLUDEINSERTTextArea.Value,1)
                                [~,fname,fext]=fileparts(app.INCLUDEINSERTTextArea.Value{k});
                                if isempty(fext);fext='.lib';end
                                fname=strcat(fname,fext);
                                copyfile(fullfile(filepath,fname),fullfile(workdir,jobid));
                                app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Copy: %s - done!',fname)];
                            end
                        end
                        writeATPCaseFiles(app,infile,jobid,vars,vartable);
                    else
                        f = uimsgbox(app,'No parameters informed. Why not using ATPDraw instead?', 'You lose, fella.','error');
                    end
                end

                if app.RunATPsolverCheckBox.Value == 1
                    nonechecked=false;
                    wdir=fullfile(workdir,jobid);

                    if ~isfile(solvercall)
                        f = uimsgbox(app,'ATP solver file not found!','You lose, fella.','error');
                        return
                    end

                    if app.PostprocessfilesCheckBox.Value==1
                        if app.ConverttoMATLABCheckBox.Value==1
                            if ~isfile(pl42matcall)
                                f = uimsgbox(app,'PL42MAT binary not found!','You lose, fella.','error');
                                return
                            end
                        end
                        if app.ConverttoCOMTRADECheckBox.Value==1
                            if ~isfile(pl42matcall)
                                f = uimsgbox(app,'PL42MAT binary not found!','You lose, fella.','error');
                                return
                            end
                            if ~isfile(matlabcall)
                                f = uimsgbox(app,'MATLAB binary not found!','You lose, fella.','error');
                                return
                            end
                        end
                    end


                    dirdata=dir(fullfile(wdir,'*.atp'));
                    if isempty(dirdata)
                        f = uimsgbox(app,'No ATP files found in the work directory!','You lose, fella.','error');
                        return
                    end
                    % if app.CommandlineButton.Value ==1
                    %     answer = uiquestdlg(app,'Before proceeding with this action, verify that ATP Launcher is properly configured: field ''Close window at end'' must be CHECKED, fields ''Output to screen'' and ''Use work directory'' must be UNCHECKED. Confirm: ', ...
                    %         'Confirm?', ...
                    %         ["Yes","No"],1,2);
                    %     if strcmp(answer,'No')
                    %         return;
                    %     end
                    % 
                    %     app.ProgrammessagesTextArea.Value{end+1} = ['****** Running ATP solver...'];
                    %     app.ProgrammessagesTextArea.Value{end+1} = ['(use MATLAB Command Window to monitor progress)'];
                    %     runAllCases(app,wdir,dirdata,solvercall);
                    % else
                        [solverpath,solverfname,~]=fileparts(solvercall);
                        app.ProgrammessagesTextArea.Value{end+1} = ['****** Building .bat file...'];
                        outfile='runAllCases.bat';
                        fid = fopen(fullfile(workdir,jobid,outfile),'wt');
                        out = '@ECHO OFF \n';
                        if ~strcmp(solverfname,'runatp')
                            istpbig=true;
                            out = [out sprintf('SET ATPDIR=%s\\\\ \n',strrep(solverpath,'\','\\'))];
                            out = [out sprintf('SET GNUDIR=%s\\\\ \n',strrep(solverpath,'\','\\')) '\n'];
                        end
                        out = [out sprintf('@cd %s',strrep(fullfile(workdir,jobid),'\','\\')) '\n'];
                        numbat=length(dirdata);
                        for k=1:numbat
                            fname=dirdata(k).name;
                            [~,fstr,~]=fileparts(fname);
                            if istpbig
                                cmd=['@if exist ' '"' strrep(fullfile(wdir,fname),'\','\\') '" ' '"' strrep(solvercall,'\','\\') '" both ' '"' strrep(fullfile(wdir,fname),'\','\\') '" s -r'];
                            else
                                cmd=['@if exist ' '"' strrep(fullfile(wdir,fname),'\','\\') '" ' '"' strrep(solvercall,'\','\\') '" ' '"' strrep(fullfile(wdir,fname),'\','\\') '"'];
                            end
                            out = [out sprintf(':case_%d \n',k)];
                            if k<numbat
                                out = [out sprintf('if exist %s.done goto case_%d \n',fstr,k+1)];
                            elseif k==numbat
                                out = [out sprintf('if exist %s.done goto end \n',fstr)];
                            end
                            out = [out sprintf('@ECHO ---------- Running case %d, file: %s \n',k,fstr)];
                            out = [out sprintf('@ECHO ---------- Running case %d, file: %s >> runAllCases.log \n',k,fstr)];
                            out = [out sprintf('@ECHO Started: %%%%date%%%% %%%%time%%%% >> runAllCases.log \n')];
                            out = [out cmd '\n'];
                            out = [out '@set size=0 \n'];
                            out = [out '@if exist ' fstr '.pl4 ' 'for /f %%%%i in ("' fstr '.pl4") do set size=%%%%~zi \n'];
                            out = [out sprintf('@if %%%%size%%%% equ 0 GOTO error \n')];
                            out = [out sprintf('@find /i /c "ERROR/ERROR/ERROR/" %s.lis > NUL \n',fstr)];
                            out = [out sprintf('@if %%%%errorlevel%%%% equ 0 GOTO error \n')];
                            out = [out sprintf('@ECHO Completed: %%%%date%%%% %%%%time%%%% >> runAllCases.log \n')];
                            out = [out sprintf('@if exist MODELS.1 Rc1_1_2ENAME MODELS.1 %s.1',fstr) '\n'];
                            out = [out sprintf('@if exist MODELS.2 RENAME MODELS.2 %s.2',fstr) '\n'];
                            if app.PostprocessfilesCheckBox.Value==1
                                if app.ConverttoMATLABCheckBox.Value==1
                                    cmd=['"' strrep(pl42matcall,'\','\\') '" ' '"' strrep(fullfile(wdir,[fstr '.pl4']),'\','\\') '"'];
                                    out = [out cmd '\n'];
                                end
                                if app.ConverttoCOMTRADECheckBox.Value==1
                                    cmd=['@if exist ' fstr '.pl4 ' '"matlab.exe" -nosplash -nodesktop -r ' '"warning(''' 'off' ''',''' 'all' ''');cd(''' strrep(fullfile(wdir),'\','\\') ''');addpath(''' strrep(fullfile(workdir,'functions'),'\','\\') ''');' ...
                                        'display(''' 'Converting file: ' fstr '.pl4' ''');' 'pl42comtrade(''' fstr ''');exit;"'];
                                    out = [out cmd '\n'];
                                end
                            end
                            out = [out '@if exist *.bin del *.bin' '\n'];
                            out = [out '@if exist *.tmp del *.tmp' '\n'];
                            out = [out '@if exist *.dbg del *.dbg' '\n'];

                            if app.CleanupPL4filesCheckBox.Value
                                out = [out sprintf('@if exist %s.pl4 del %s.pl4',fstr, fstr) '\n'];
                            end
                            
                            if ~app.ConverttoMATLABCheckBox.Value
                                out = [out sprintf('@if exist %s.mat del %s.mat',fstr, fstr) '\n'];
                            end
                            matexp = sprintf('.%s_postprocdata.mat',fstr);
                            out = [out sprintf('@if exist %s.pl4 del %s.pl4',matexp, matexp) '\n'];
                            out = [out sprintf('@RENAME %s %s.done',fname,fstr) '\n'];
                        end
                        out = [out 'goto end' '\n'];
                        out = [out ':error' '\n'];
                        out = [out sprintf('@ECHO TERMINATED IN ERROR: %%%%date%%%% %%%%time%%%% >> runAllCases.log \n')];
                        out = [out '@echo ***ERROR: One or more ATP calls terminated in error and the batch was interrupted. \n'];
                        out = [out ':end' '\n'];
                        out = [out '@if exist *.bin del *.bin' '\n'];
                        out = [out '@if exist *.tmp del *.tmp' '\n'];
                        out = [out '@if exist *.dbg del *.dbg' '\n'];
                        out = [out sprintf('@ECHO ALL DONE! \n')];
                        fprintf(fid, out);
                        fclose(fid);

                        outfile='resetRunQueue.bat';
                        fid = fopen(fullfile(workdir,jobid,outfile),'wt');
                        out = '@ECHO OFF \n';
                        out = [out '@rename *.done *.atp' '\n'];
                        out = [out '@del runAllCases.log' '\n'];
                        fprintf(fid, out);
                        fclose(fid);

                        if app.BuildRunBatButton.Value==1
                            batrun=fullfile(workdir,jobid,'runAllCases.bat');
                            if isfile(batrun)
                                app.ProgrammessagesTextArea.Value{end+1} = ['****** Launching batch in a separate process...'];
                                app.ProgrammessagesTextArea.Value{end+1} = ['In case of issues, verify that ATP Launcher is properly configured: field ''Close window at end'' must be CHECKED, fields ''Output to screen'' and ''Use work directory'' must be UNCHECKED.'];
                                
                                if app.UseWineLinuxCheckBox.Value ==1
                                    cmd = ['wine ' fullfile(workdir,jobid,'runAllCases.bat')];
                                else
                                    cmd=['start cmd /k ' '"cd /d "' fullfile(workdir,jobid) '" & runAllCases.bat"'];
                                end
                                system(cmd);
                            end
                        end
                    % end


                    app.ProgrammessagesTextArea.Value{end+1} = ['All done!'];

                end

                if app.PostprocessfilesCheckBox.Value == 1
                    nonechecked=false;
                end

            catch ME
                f = uimsgbox(app,sprintf('Error running ATP-Batlab! \n\n Identifier: %s \n Message: %s',ME.identifier,ME.message),'You lose, fella.','error');
            end
            if nonechecked
                f = uimsgbox(app,'No task chosen!', 'You lose, fella.','error');
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Button pushed function: AddrowButton_2
        function AddrowButton_2Pushed(app, event)
            newrow={'VAR','true',9999,9999};
            app.UITable_2.Data=[app.UITable_2.Data;newrow];
            redefRowLabels(app)
        end

        % Cell selection callback: UITable_2
        function UITable_2CellSelection(app, event)
            if ~isempty(app.UITable.Data)
                indices = event.Indices;
                if ~isempty(indices)
                    selectedRow = indices(1);
                    app.row2Delete_2.Value=string(selectedRow);
                end

            end
        end

        % Button pushed function: DeleteselectedrowButton_2
        function DeleteselectedrowButton_2Pushed(app, event)
            delrow=str2double(app.row2Delete_2.Value);
            if ~isempty(app.UITable_2.Data)
                if isnan(delrow)
                    app.UITable_2.Data(end,:)=[];
                else
                    app.UITable_2.Data(delrow,:)=[];
                end
                redefRowLabels(app)
            end
        end

        % Menu selected function: SendtoConditionalvariablesMenu
        function SendtoConditionalvariablesMenuSelected(app, event)
            thisrow=str2double(app.row2Delete.Value);
            if ~isempty(app.UITable.Data)
                if ~isnan(thisrow)
                    thisdata=app.UITable.Data(thisrow,:);
                    thisdata.Type="true";
                    app.UITable_2.Data=[app.UITable_2.Data;table2cell(thisdata)];
                    app.UITable.Data(thisrow,:)=[];
                end
                redefRowLabels(app)
            end
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            
        end

        % Menu selected function: SendtoModelvariablesMenu
        function SendtoModelvariablesMenuSelected(app, event)
            thisrow=str2double(app.row2Delete_2.Value);
            if ~isempty(app.UITable_2.Data)
                if ~isnan(thisrow)
                    thisdata=app.UITable_2.Data(thisrow,:);
                    thisval=double(thisdata.NewValue);
                    if isnan(thisval)
                        thisdata.Trigger="string"; %ugly, messy and impossible to debug after a couple months. LOVELY!
                    else
                        thisdata.Trigger="numeric";
                    end
                    app.UITable.Data=[app.UITable.Data;table2cell(thisdata)];
                    app.UITable_2.Data(thisrow,:)=[];
                end
                redefRowLabels(app)
            end
        end

        % Cell edit callback: UITable_2
        function UITable_2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(1,2)==4
                try
                    pattern = '\{[^}]*\}';
                    pattern2 = '\{([^}]*)\}';
                    replacement = '0';
                    matches = regexp(newData, pattern2, 'tokens');
                    newData=regexprep(newData, pattern, replacement);
                    test=eval(newData);
                    if ~(isscalar(test) || ischar(test))
                        f = uimsgbox(app,'The outcome of ''ValueIfTrue'' must be a scalar or string.', 'You lose, fella.','error');
                        app.UITable_2.Data.NewValue(indices(1))='';
                        return;
                    end
                    if ~isempty(matches)
                        currentVars=app.UITable.Data.PARAM;
                        % Loop through each match and check for existence in currentVars
                        for i = 1:length(matches)
                            extractedString = matches{i}{1}; % Extract the string from the cell array
                            if ~any(currentVars == extractedString)
                                f = uimsgbox(app,'The specified variable in ''ValueIfTrue'' does not exist in the model variables declared.', 'You lose, fella.','error');
                                app.UITable_2.Data.NewValue(indices(1))='';
                            end
                        end
                    end

                catch
                    f = uimsgbox(app,'Invalid data or MATLAB operator.','You lose, fella.','error');
                    app.UITable_2.Data.NewValue(indices(1))='';
                    return;
                end
            elseif indices(1,2)==3
                try
                    pattern = '\{[^}]*\}';
                    pattern2 = '\{([^}]*)\}';
                    replacement = '0';
                    matches = regexp(newData, pattern2, 'tokens');
                    newData=regexprep(newData, pattern, replacement);
                    test=eval(newData);
                    if ~(isscalar(test) || ischar(test))
                        f = uimsgbox(app,'The outcome of ''ValueIfFalse'' must be a scalar or string.', 'You lose, fella.','error');
                        app.UITable_2.Data.TriggerValue(indices(1))='';
                        return;
                    end
                    if ~isempty(matches)
                        currentVars=app.UITable.Data.PARAM;
                        % Loop through each match and check for existence in currentVars
                        for i = 1:length(matches)
                            extractedString = matches{i}{1}; % Extract the string from the cell array
                            if ~any(currentVars == extractedString)
                                f = uimsgbox(app,'The specified variable in ''ValueIfFalse'' does not exist in the model variables declared.', 'You lose, fella.','error');
                                app.UITable_2.Data.TriggerValue(indices(1))='';
                            end
                        end
                    end

                catch
                    f = uimsgbox(app,'Invalid data or MATLAB operator.','You lose, fella.','error');
                    app.UITable_2.Data.TriggerValue(indices(1))='';
                    return;
                end
            elseif indices(1,2)==2
                try
                    pattern = '\{[^}]*\}';
                    pattern2 = '\{([^}]*)\}';
                    replacement = 'true';
                    matches = regexp(newData, pattern2, 'tokens');
                    newData=regexprep(newData, pattern, replacement);
                    test=eval(newData);
                    if ~islogical(test)
                        f = uimsgbox(app,'The outcome of ''Conditional'' must be a valid boolean (true/false).', 'You lose, fella.','error');
                        app.UITable_2.Data.Trigger(indices(1))='';
                        return;
                    end
                    if ~isempty(matches)
                        currentVars=app.UITable.Data.PARAM;
                        % Loop through each match and check for existence in currentVars
                        for i = 1:length(matches)
                            extractedString = matches{i}{1}; % Extract the string from the cell array
                            if ~any(currentVars == extractedString)
                                f = uimsgbox(app,'The specified variable in ''Conditional'' does not exist in the model variables declared.', 'You lose, fella.','error');
                                app.UITable_2.Data.Trigger(indices(1))='';
                            end
                        end
                    end

                catch
                    f = uimsgbox(app,'Invalid logical expression or MATLAB operator.','You lose, fella.','error');
                    app.UITable_2.Data.Trigger(indices(1))='';
                    return;
                end
            elseif indices(1,2)==1
                currentVars=app.UITable.Data.PARAM;
                if any(currentVars == newData)
                    f = uimsgbox(app,'The specified variable in ''$PARAMETER'' already exists in the model variables declared, leading to a duplicate variable situation where I don''t know what to do. In other words: a variable can be regular (model variable) or conditional, but not both. Use your creativity to fix this unfortunate state of affairs and try again.', 'You lose, fella.','error');
                    app.UITable_2.Data.PARAM(indices(1))='';
                end

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ATPBatlab = uifigure('Visible', 'off');
            app.ATPBatlab.Name = 'ATP-Batlab';
            app.SaveinputsessionMenu = uimenu(app.ATPBatlab);
            app.SaveinputsessionMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveinputsessionMenuSelected, true);
            app.SaveinputsessionMenu.Text = '🖪 Save input session';
            app.LoadinputsessionMenu = uimenu(app.ATPBatlab);
            app.LoadinputsessionMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadinputsessionMenuSelected, true);
            app.LoadinputsessionMenu.Text = '📁 Load input session';
            app.ParseATPfileMenu = uimenu(app.ATPBatlab);
            app.ParseATPfileMenu.MenuSelectedFcn = createCallbackFcn(app, @Load2TableButtonPushed, true);
            app.ParseATPfileMenu.Text = '🔎 Parse ATP file';
            app.RunMenu = uimenu(app.ATPBatlab);
            app.RunMenu.MenuSelectedFcn = createCallbackFcn(app, @RunMenuSelected, true);
            app.RunMenu.Text = '🏁 Run!';
            app.ExitMenu = uimenu(app.ATPBatlab);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = '🚪 Exit';
            app.TabGroup = uitabgroup(app.ATPBatlab);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = '🎛 Parameters';
            app.LoadFileButton = uibutton(app.ParametersTab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = '📂';
            app.SourcefileatpTextAreaLabel = uilabel(app.ParametersTab);
            app.SourcefileatpTextAreaLabel.Text = 'Source file [.atp]:';
            app.SourcefileatpEditField = uitextarea(app.ParametersTab);
            app.SourcefileatpEditField.Editable = 'off';
            app.Load2TableButton = uibutton(app.ParametersTab, 'push');
            app.Load2TableButton.ButtonPushedFcn = createCallbackFcn(app, @Load2TableButtonPushed, true);
            app.Load2TableButton.Tooltip = {'Parse ATP file'};
            app.Load2TableButton.Text = '🔎';
            app.JobIDEditFieldLabel = uilabel(app.ParametersTab);
            app.JobIDEditFieldLabel.Text = 'JobID:';
            app.JobIDEditField = uieditfield(app.ParametersTab, 'text');
            app.ProjectdescriptionTextAreaLabel = uilabel(app.ParametersTab);
            app.ProjectdescriptionTextAreaLabel.Text = 'Project description:';
            app.ProjectdescriptionTextArea = uitextarea(app.ParametersTab);
            app.INCLUDEINSERTTextAreaLabel = uilabel(app.ParametersTab);
            app.INCLUDEINSERTTextAreaLabel.Text = '$INCLUDE / $INSERT';
            app.INCLUDEINSERTTextArea = uitextarea(app.ParametersTab);
            app.INCLUDEINSERTTextArea.Editable = 'off';
            app.EditCheckBox_Inc = uicheckbox(app.ParametersTab);
            app.EditCheckBox_Inc.ValueChangedFcn = createCallbackFcn(app, @EditCheckBox_IncValueChanged, true);
            app.EditCheckBox_Inc.Text = 'Edit';
            app.UITable = uitable(app.ParametersTab);
            app.UITable.ColumnName = {'$PARAMETER'; 'Type'; 'CurrentValue'; 'NewValues'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddrowButton = uibutton(app.ParametersTab, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Text = '➕ Add row';
            app.DeleteselectedrowButton = uibutton(app.ParametersTab, 'push');
            app.DeleteselectedrowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedrowButtonPushed, true);
            app.DeleteselectedrowButton.Text = '🗙 Delete selected row';
            app.row2Delete = uieditfield(app.ParametersTab, 'text');
            app.row2Delete.Visible = 'off';
            app.ModelvariablesandvaluerangesLabel = uilabel(app.ParametersTab);
            app.ModelvariablesandvaluerangesLabel.Text = 'Model variables and value ranges';
            app.ATPheaderPanel = uipanel(app.ParametersTab);
            app.ATPheaderPanel.Title = 'ATP header';
            app.CoptEditFieldLabel = uilabel(app.ATPheaderPanel);
            app.CoptEditFieldLabel.Text = 'Copt';
            app.CoptEditField = uieditfield(app.ATPheaderPanel, 'numeric');
            app.CoptEditField.Limits = [0 Inf];
            app.CoptEditField.Value = 60;
            app.XoptEditFieldLabel = uilabel(app.ATPheaderPanel);
            app.XoptEditFieldLabel.Text = 'Xopt';
            app.XoptEditField = uieditfield(app.ATPheaderPanel, 'numeric');
            app.XoptEditField.Limits = [0 Inf];
            app.XoptEditField.Value = 60;
            app.deltaTsEditFieldLabel = uilabel(app.ATPheaderPanel);
            app.deltaTsEditFieldLabel.Text = 'delta T [s]:';
            app.dTsEditField = uieditfield(app.ATPheaderPanel, 'numeric');
            app.dTsEditField.Limits = [0 Inf];
            app.dTsEditField.Value = 1e-06;
            app.TmaxsEditFieldLabel = uilabel(app.ATPheaderPanel);
            app.TmaxsEditFieldLabel.Text = 'Tmax [s]:';
            app.TmaxsEditField = uieditfield(app.ATPheaderPanel, 'numeric');
            app.TmaxsEditField.Limits = [0 100];
            app.TmaxsEditField.Value = 0.1;
            app.RefactorTab = uitab(app.TabGroup);
            app.RefactorTab.Title = '🧪 Refactor';
            app.UITable_2 = uitable(app.RefactorTab);
            app.UITable_2.ColumnName = {'$PARAMETER'; 'Condition'; 'ValueIfFalse'; 'ValueIfTrue'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = true;
            app.UITable_2.CellEditCallback = createCallbackFcn(app, @UITable_2CellEdit, true);
            app.UITable_2.CellSelectionCallback = createCallbackFcn(app, @UITable_2CellSelection, true);
            app.UITable_2.Tooltip = {'Fields ''Condition'' ''ValueIfFalse'' and ''ValueIfTrue'': use {VAR} enclosed in brackets to refer to a previously defined $PARAMETER in the ''Model variables'' field. Example:'; 'Condition: ({R}==10 && {T}<=5), ValueIfFalse: {X1}, ValueIfTrue: {X0}. If a {VAR} is not previously defined, the program will halt with an error message.'};
            app.ConditionalvariablesLabel = uilabel(app.RefactorTab);
            app.ConditionalvariablesLabel.Text = 'Conditional variables';
            app.AddrowButton_2 = uibutton(app.RefactorTab, 'push');
            app.AddrowButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddrowButton_2Pushed, true);
            app.AddrowButton_2.Text = '➕ Add row';
            app.DeleteselectedrowButton_2 = uibutton(app.RefactorTab, 'push');
            app.DeleteselectedrowButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedrowButton_2Pushed, true);
            app.DeleteselectedrowButton_2.Text = '🗙 Delete selected row';
            app.row2Delete_2 = uieditfield(app.RefactorTab, 'text');
            app.row2Delete_2.Visible = 'off';
            app.UITable_3 = uitable(app.RefactorTab);
            app.UITable_3.ColumnName = {'Line'; '<From>'; '<To>'; 'Output'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnEditable = true;
            app.RedefineBRANCHoutputsLabel = uilabel(app.RefactorTab);
            app.RedefineBRANCHoutputsLabel.Text = 'Redefine /BRANCH outputs';
            app.UITable_4 = uitable(app.RefactorTab);
            app.UITable_4.ColumnName = {'Line'; '<From>'; '<To>'; 'Output'};
            app.UITable_4.RowName = {};
            app.UITable_4.ColumnEditable = true;
            app.RedefineSWITCHoutputsLabel = uilabel(app.RefactorTab);
            app.RedefineSWITCHoutputsLabel.Text = 'Redefine /SWITCH outputs';
            app.TasksTab = uitab(app.TabGroup);
            app.TasksTab.Title = '📝 Tasks';
            app.SolvercallButtonGroup = uibuttongroup(app.TasksTab);
            app.SolvercallButtonGroup.Title = 'Solver call';
            app.BuildbatfileButton = uiradiobutton(app.SolvercallButtonGroup);
            app.BuildbatfileButton.Text = 'Build .bat file';
            app.BuildbatfileButton.Value = true;
            app.BuildRunBatButton = uiradiobutton(app.SolvercallButtonGroup);
            app.BuildRunBatButton.Text = 'Build & run .bat file';
            app.TasksButtonGroup = uibuttongroup(app.TasksTab);
            app.TasksButtonGroup.Title = 'Tasks';
            app.BuildcasefilesCheckBox = uicheckbox(app.TasksButtonGroup);
            app.BuildcasefilesCheckBox.Text = 'Build case files';
            app.BuildcasefilesCheckBox.Value = true;
            app.RunATPsolverCheckBox = uicheckbox(app.TasksButtonGroup);
            app.RunATPsolverCheckBox.Text = 'Run ATP solver';
            app.RunATPsolverCheckBox.Value = true;
            app.PostprocessfilesCheckBox = uicheckbox(app.TasksButtonGroup);
            app.PostprocessfilesCheckBox.Text = 'Post-process files';
            app.PostprocessfilesCheckBox.Value = true;
            app.ConsolemessagesTextAreaLabel = uilabel(app.TasksTab);
            app.ConsolemessagesTextAreaLabel.Text = 'Console messages:';
            app.ProgrammessagesTextArea = uitextarea(app.TasksTab);
            app.ProgrammessagesTextArea.Editable = 'off';
            app.ClearmessagesButton = uibutton(app.TasksTab, 'push');
            app.ClearmessagesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearmessagesButtonPushed, true);
            app.ClearmessagesButton.Text = '🗑️ Clear messages';
            app.LaunchdebugButton = uibutton(app.TasksTab, 'push');
            app.LaunchdebugButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchdebugButtonPushed, true);
            app.LaunchdebugButton.Text = '🐜 Launch debug ';
            app.PostprocessingButtonGroup = uibuttongroup(app.TasksTab);
            app.PostprocessingButtonGroup.Title = 'Post-processing';
            app.CleanupPL4filesCheckBox = uicheckbox(app.PostprocessingButtonGroup);
            app.CleanupPL4filesCheckBox.Text = 'Clean up PL4 files';
            app.CleanupPL4filesCheckBox.Value = true;
            app.ConverttoMATLABCheckBox = uicheckbox(app.PostprocessingButtonGroup);
            app.ConverttoMATLABCheckBox.Text = 'Write MAT-file';
            app.ConverttoCOMTRADECheckBox = uicheckbox(app.PostprocessingButtonGroup);
            app.ConverttoCOMTRADECheckBox.Text = 'Write COMTRADE';
            app.ConverttoCOMTRADECheckBox.Value = true;
            app.PathstoexecutablesandoutputfolderPanel = uipanel(app.TasksTab);
            app.PathstoexecutablesandoutputfolderPanel.Title = 'Paths to executables and output folder';
            app.LoadATPButton = uibutton(app.PathstoexecutablesandoutputfolderPanel, 'push');
            app.LoadATPButton.ButtonPushedFcn = createCallbackFcn(app, @LoadATPButtonPushed, true);
            app.LoadATPButton.Text = '📂';
            app.ATPsolverEditFieldLabel = uilabel(app.PathstoexecutablesandoutputfolderPanel);
            app.ATPsolverEditFieldLabel.Text = 'ATP solver:';
            app.ATPsolverEditField = uieditfield(app.PathstoexecutablesandoutputfolderPanel, 'text');
            app.ATPsolverEditField.Value = '%ATPPATH%\tools\runATP.exe';
            app.LoadWorkdirButton = uibutton(app.PathstoexecutablesandoutputfolderPanel, 'push');
            app.LoadWorkdirButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWorkdirButtonPushed, true);
            app.LoadWorkdirButton.Text = '📂';
            app.WorkdirectoryEditFieldLabel = uilabel(app.PathstoexecutablesandoutputfolderPanel);
            app.WorkdirectoryEditFieldLabel.Text = 'Work directory:';
            app.WorkdirectoryEditField = uieditfield(app.PathstoexecutablesandoutputfolderPanel, 'text');
            app.LoadPl42matButton = uibutton(app.PathstoexecutablesandoutputfolderPanel, 'push');
            app.LoadPl42matButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPl42matButtonPushed, true);
            app.LoadPl42matButton.Text = '📂';
            app.PL42MATEditFieldLabel = uilabel(app.PathstoexecutablesandoutputfolderPanel);
            app.PL42MATEditFieldLabel.Text = 'PL42MAT:';
            app.PL42MATEditField = uieditfield(app.PathstoexecutablesandoutputfolderPanel, 'text');
            app.PL42MATEditField.Value = '%WORKDIR%\tools\Pl42mat.exe';
            app.LoadMATLABButton = uibutton(app.PathstoexecutablesandoutputfolderPanel, 'push');
            app.LoadMATLABButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMATLABButtonPushed, true);
            app.LoadMATLABButton.Text = '📂';
            app.MATLABbinaryEditFieldLabel = uilabel(app.PathstoexecutablesandoutputfolderPanel);
            app.MATLABbinaryEditFieldLabel.Text = 'MATLAB binary:';
            app.MATLABbinaryEditField = uieditfield(app.PathstoexecutablesandoutputfolderPanel, 'text');
            app.MATLABbinaryEditField.Value = '%MATLABROOTDIR%\bin\win64\MATLAB.exe';
            app.FilesinJOBIDTextAreaLabel = uilabel(app.TasksTab);
            app.FilesinJOBIDTextAreaLabel.Text = 'Files in %JOBID%';
            app.FilesinJOBIDTextArea = uitextarea(app.TasksTab);
            app.FilesinJOBIDTextArea.Editable = 'off';
            app.FilesinJOBIDTextArea.Value = {'*.dat; *.cfg; *.pl4; *.mat'};
            app.UseWineLinuxCheckBox = uicheckbox(app.TasksTab);
            app.UseWineLinuxCheckBox.Enable = 'off';
            app.UseWineLinuxCheckBox.Visible = 'off';
            app.UseWineLinuxCheckBox.Text = 'Use Wine  (Linux)';
            app.ContextMenu = uicontextmenu(app.ATPBatlab);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.SendtoConditionalvariablesMenu = uimenu(app.ContextMenu);
            app.SendtoConditionalvariablesMenu.MenuSelectedFcn = createCallbackFcn(app, @SendtoConditionalvariablesMenuSelected, true);
            app.SendtoConditionalvariablesMenu.Text = '➡️ Send to ''Conditional variables''';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.ATPBatlab);
            app.SendtoModelvariablesMenu = uimenu(app.ContextMenu2);
            app.SendtoModelvariablesMenu.MenuSelectedFcn = createCallbackFcn(app, @SendtoModelvariablesMenuSelected, true);
            app.SendtoModelvariablesMenu.Text = '⬅️ Send to ''Model variables''';
            % Assign app.ContextMenu2
            app.UITable_2.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.ATPBatlab.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = atpbatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ATPBatlab)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ATPBatlab)
        end
    end
end