classdef main2021 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutoATPCaseGen                 matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CasedataTab                    matlab.ui.container.Tab
        EditCheckBox_Inc               matlab.ui.control.CheckBox
        CaseDropDown                   matlab.ui.control.DropDown
        CaseLabel                      matlab.ui.control.Label
        EditCheckBox                   matlab.ui.control.CheckBox
        LineCircuitDropDown            matlab.ui.control.DropDown
        LineCircuitLabel               matlab.ui.control.Label
        FaultmodelDropDown             matlab.ui.control.DropDown
        FaultmodelLabel                matlab.ui.control.Label
        XoptEditField                  matlab.ui.control.NumericEditField
        XoptEditFieldLabel             matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        INCLUDEINSERTTextArea          matlab.ui.control.TextArea
        INCLUDEINSERTTextAreaLabel     matlab.ui.control.Label
        TmaxsEditField                 matlab.ui.control.NumericEditField
        TmaxsEditFieldLabel            matlab.ui.control.Label
        dTsEditField                   matlab.ui.control.NumericEditField
        dTsEditFieldLabel              matlab.ui.control.Label
        CoptEditField                  matlab.ui.control.NumericEditField
        CoptEditFieldLabel             matlab.ui.control.Label
        LoadinputsessionButton         matlab.ui.control.Button
        row2Delete                     matlab.ui.control.EditField
        ProjectdescriptionTextArea     matlab.ui.control.TextArea
        ProjectdescriptionTextAreaLabel  matlab.ui.control.Label
        SaveinputsessionButton         matlab.ui.control.Button
        JobIDEditField                 matlab.ui.control.EditField
        JobIDEditFieldLabel            matlab.ui.control.Label
        DeleterowButton                matlab.ui.control.Button
        AddrowButton                   matlab.ui.control.Button
        Load2TableButton               matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        TemplateATPfileatpEditField    matlab.ui.control.EditField
        TemplateATPfileatpEditFieldLabel  matlab.ui.control.Label
        LoadFileButton                 matlab.ui.control.Button
        RunTab                         matlab.ui.container.Tab
        LaunchdebugButton              matlab.ui.control.Button
        UseWineLinuxCheckBox           matlab.ui.control.CheckBox
        StartButton                    matlab.ui.control.Button
        ClearmessagesButton            matlab.ui.control.Button
        ProgrammessagesTextArea        matlab.ui.control.TextArea
        ProgrammessagesTextAreaLabel   matlab.ui.control.Label
        TasksButtonGroup               matlab.ui.container.ButtonGroup
        UploadtoremoteCheckBox         matlab.ui.control.CheckBox
        PostprocessfilesCheckBox       matlab.ui.control.CheckBox
        RunATPsolverCheckBox           matlab.ui.control.CheckBox
        BuildcasefilesCheckBox         matlab.ui.control.CheckBox
        SolvercallButtonGroup          matlab.ui.container.ButtonGroup
        BuildbatfileButton             matlab.ui.control.RadioButton
        CommandlineButton              matlab.ui.control.RadioButton
        MiscTab                        matlab.ui.container.Tab
        UploadtoremoteButtonGroup      matlab.ui.container.ButtonGroup
        FilesinWORKDIRTextArea         matlab.ui.control.TextArea
        FilesinWORKDIRTextAreaLabel    matlab.ui.control.Label
        UploadnowButton                matlab.ui.control.Button
        RemotedirectoryEditField       matlab.ui.control.EditField
        RemotedirectoryEditFieldLabel  matlab.ui.control.Label
        PortEditField                  matlab.ui.control.EditField
        PortEditFieldLabel             matlab.ui.control.Label
        PasswordEditField              matlab.ui.control.EditField
        PasswordEditFieldLabel         matlab.ui.control.Label
        LoginEditField                 matlab.ui.control.EditField
        LoginEditFieldLabel            matlab.ui.control.Label
        FTPserverEditField             matlab.ui.control.EditField
        FTPserverEditFieldLabel        matlab.ui.control.Label
        COMTRADEoutputButtonGroup      matlab.ui.container.ButtonGroup
        UseTWfiltersCheckBox           matlab.ui.control.CheckBox
        UseRDPfiltersCheckBox          matlab.ui.control.CheckBox
        PathstoexecutablesandoutputfolderPanel  matlab.ui.container.Panel
        MATLABbinaryEditField          matlab.ui.control.EditField
        MATLABbinaryEditFieldLabel     matlab.ui.control.Label
        LoadMATLABButton               matlab.ui.control.Button
        PL42MATEditField               matlab.ui.control.EditField
        PL42MATEditFieldLabel          matlab.ui.control.Label
        LoadPl42matButton              matlab.ui.control.Button
        WorkdirectoryEditField         matlab.ui.control.EditField
        WorkdirectoryEditFieldLabel    matlab.ui.control.Label
        LoadWorkdirButton              matlab.ui.control.Button
        ATPsolverEditField             matlab.ui.control.EditField
        ATPsolverEditFieldLabel        matlab.ui.control.Label
        LoadATPButton                  matlab.ui.control.Button
        PostprocessingButtonGroup      matlab.ui.container.ButtonGroup
        ConverttoCOMTRADECheckBox      matlab.ui.control.CheckBox
        ConverttoMATLABCheckBox        matlab.ui.control.CheckBox
        CleanupPL4filesCheckBox        matlab.ui.control.CheckBox
    end


    methods (Access = private)

        function [isgood, msg] = validateFilename(app)
            fname=app.TemplateATPfileatpEditField.Value;
            isgood=true;
            msg='';
            if  ~exist(fname, 'file')
                msg='Invalid file name!';
                isgood=false;
                return;
            end
        end


        function [results, msg] = loadFromFile(app,fname)
            results=[];
            msg=[];
            try
                fid=fopen(fname);
                data=textscan(fid, '%s', 'delimiter', '\n');
                fclose(fid);
                data=data{1,1};
            catch
                msg='File contains invalid data!';
                try;fclose(fid);catch;end;
                return;
            end
            idx = find(contains(data,'C  dT  >< Tmax >< Xopt >< Copt ><Epsiln>'));
            tmp_str=string(data(idx(1)+1));
            tmp_str=split(tmp_str,' ');
            tmp_str(tmp_str=="")=[];
            results.dT=tmp_str(1);
            results.Tmax=tmp_str(2);
            results.Xopt=tmp_str(3);
            results.Copt=tmp_str(4);


            idx = find(contains(data,'$INCLUDE'));
            idx = [idx; find(contains(data,'$INSERT'))];
            for k=1:length(idx)
                tmp_str=string(data(idx(k)));
                tmp_str=split(tmp_str,',');
                inc_names{k,1}=char(strtrim(tmp_str(2)));
            end
            results.include=unique(inc_names);

            idx = find(contains(data,'$PARAMETER'));
            c=0;
            for k = idx(1)+1:1:idx(end)-1
                tmp_str=string(data(k));
                tmp_str=split(tmp_str,'=');
                if contains(tmp_str(2),'$$')
                    % found a primary variable
                    c=c+1;
                    varnames{c,1}=strtrim(tmp_str(1));
                    tmp_str2=split(tmp_str(2),'$$');
                    varvalues{c,1}=strtrim(tmp_str2(1));
                    vartype{c,1}='numeric';
                elseif contains(tmp_str(2),'''')
                    % found a string variable
                    c=c+1;
                    varnames{c,1}=strtrim(tmp_str(1));
                    varvalues{c,1}=strtrim(tmp_str(2));
                    vartype{c,1}='string';
                end
            end
            results.params=[varnames vartype varvalues varvalues];
        end


        function out = redefRowLabels(app)
            nrows = size(app.UITable.Data,1);
            app.UITable.RowName=string(1:nrows);
        end

        function results = saveAllData(app)

            results=false;
            %[testdir,msg]=validateWorkDir(app);
            %if ~testdir
            %    f = msgbox(msg, 'You lose, fella.','error');
            %    return;
            %end

            [testfile,msg]=validateProjID(app);
            if ~testfile
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end

            %ok now do stuff
            projID=app.JobIDEditField.Value;
            [~,wdir] = uiputfile('*.mat','Save input',[projID '.mat']);

            %             wdir=app.WorkingdirectoryEditField.Value;
            if wdir ~= 0
                projdir=fullfile(wdir);
                %             orig_state = warning;warning('off','all');
                %             mkdir(projdir); warning(orig_state);
                %             mkdir(projdir);
                fname=[projID '.mat'];
                atpcasegen_data = readAllAppValues(app);
                save(fullfile(projdir,fname),'atpcasegen_data');
                %             warning(orig_state);
                results=true;
            end
        end

        function results = writeAllAppValues(app,atpcasegen_data)

            props = properties(app);

            for iprop = 1:length(props)
                thisprop = props{iprop};

                if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                        strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                        strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                        strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable')


                    if isprop(app.(thisprop),'Value')
                        app.(thisprop).Value=atpcasegen_data.(thisprop).Value;
                    end

                    app.(thisprop).Enable=atpcasegen_data.(thisprop).Enable;
                    app.(thisprop).Visible=atpcasegen_data.(thisprop).Visible;

                    if isprop(app.(thisprop),'Editable')
                        app.(thisprop).Editable=atpcasegen_data.(thisprop).Editable;
                    end

                    if isprop(app.(thisprop),'List')
                        app.(thisprop).List=atpcasegen_data.(thisprop).List;
                    end

                    if isprop(app.(thisprop),'Data')
                        app.(thisprop).Data=atpcasegen_data.(thisprop).Data;
                    end

                end

            end
        end

        function appdata = readAllAppValues(app)
            appdata=struct;

            props = properties(app);

            for iprop = 1:length(props)
                thisprop = props{iprop};

                if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                        strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                        strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                        strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable')

                    appdata.(thisprop)=struct;

                    if isprop(app.(thisprop),'Value')
                        appdata.(thisprop).Value=app.(thisprop).Value;
                    end

                    appdata.(thisprop).Enable=app.(thisprop).Enable;
                    appdata.(thisprop).Visible=app.(thisprop).Visible;

                    if isprop(app.(thisprop),'Editable')
                        appdata.(thisprop).Editable=app.(thisprop).Editable;
                    end

                    if isprop(app.(thisprop),'List')
                        appdata.(thisprop).List=app.(thisprop).List;
                    end

                    if isprop(app.(thisprop),'Data')
                        appdata.(thisprop).Data=app.(thisprop).Data;
                    end

                end

            end
        end

        function [isgood, msg] = validateProjID(app)
            projID=app.JobIDEditField.Value;
            isgood=true;
            msg='';
            BadChar = '<>:"/\|?*';
            BadName = {'CON', 'PRN', 'AUX', 'NUL', 'CLOCK$', ...
                'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', ...
                'COM7', 'COM8', 'COM9', ...
                'LPT1', 'LPT2', 'LPT3', 'LPT4', 'LPT5', 'LPT6', ...
                'LPT7', 'LPT8', 'LPT9'};
            if isempty(projID)
                isgood=false;
                msg='You must specify a project ID!';
                return;
            else
                bad = ismember(BadChar, projID);
                if any(bad)
                    msg = ['Project ID contains bad characters: ', BadChar(bad)];
                    isgood=false;
                    return;
                elseif any(projID < 32)
                    msg = ['Project ID  contains non printable characters, ASCII:', sprintf(' %d', projID(projID < 32))];
                    isgood=false;
                    return;
                elseif ~isempty(projID) && (projID(end) == ' ' || projID(end) == '.')
                    msg = 'Project ID must not contain a trailing space or dot.';
                    isgood=false;
                    return;
                else
                    [~, name] = fileparts(projID);
                    if any(strcmpi(name, BadName))
                        msg = ['Project ID not allowed: ', name];
                        isgood=false;
                        return;
                    end
                end
            end
        end

        function [vars, vartable] = buildCaseData(app)
            numvars=size(app.UITable.Data,1);
            str_allcomb='allcomb(';

            for k=1:numvars
                vars(k).param=app.UITable.Data.PARAM(k);
                vars(k).type=app.UITable.Data.Type(k);
                tmp_eval=eval(app.UITable.Data.NewValues(k));
                if iscell(tmp_eval)
                    vars(k).data=tmp_eval;
                elseif isnumeric(tmp_eval)
                    vars(k).data=num2cell(tmp_eval);
                elseif ischar(tmp_eval)
                    vars(k).data={tmp_eval};
                end
                str_allcomb=[str_allcomb sprintf('vars(%d).data,',k)];
            end
            str_allcomb=[str_allcomb(1:end-1) ')'];
            vartable=eval(str_allcomb);

        end


        function [outfile] = writeATPCaseFiles(app,infile,jobid,vars,allcases_data)
            outfile=[];
            workdir=app.WorkdirectoryEditField.Value;
            pl4bin=app.PL42MATEditField.Value;
            fmodel=app.FaultmodelDropDown.Value;
            linecir=app.LineCircuitDropDown.Value;
            caseid=app.CaseDropDown.Value;
            templatedir=fileparts(app.TemplateATPfileatpEditField.Value);
            purgepl4=app.CleanupPL4filesCheckBox.Value;
            keepmatfiles=app.ConverttoMATLABCheckBox.Value;
            try
                fid=fopen(infile);
                data=textscan(fid, '%s', 'Delimiter', '\n', 'CollectOutput', true, 'Whitespace', '');
                fclose(fid);
                data=data{1,1};

                numvars=size(vars,2);
                numcases=size(allcases_data,1);
                app.ProgrammessagesTextArea.Value{end+1} = ['****** Building ATP files...'];
                for j=1:numcases
                    thiscasedata=allcases_data(j,:);
                    app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Now working case #%d.',j)];
                    for k=1:numvars
                        idx = find(startsWith(data,[char(vars(k).param) ' =']));
                        if vars(k).type == 'numeric'
                            data{idx} = [char(vars(k).param) ' = ' sprintf('%1.2f',allcases_data{j,k}) ' $$'];
                        elseif vars(k).type == 'string'
                            data{idx} = [char(vars(k).param) ' = ' sprintf('''%s''',allcases_data{j,k})];
                        end
                    end
                    idx = find(startsWith(data,'FSECI ='));
                    nodenam=thiscasedata(cellfun(@ischar,thiscasedata));
                    fsec=findNodeSec(linecir,templatedir,nodenam);
                    if ~isempty(idx)
                        data{idx} = ['FSECI = ' sprintf('%1.1f',fsec) ' $$'];
                    end
                    idx = find(startsWith(data,'LTFLTI ='));
                    if strcmp(linecir,'LEC_SIL_C1')
                        ltflt=11;
                    elseif strcmp(linecir,'LEC_SIL_C2')
                        ltflt=12;
                    elseif strcmp(linecir,'SIL_ORX_C1')
                        ltflt=21;
                    elseif strcmp(linecir,'SIL_ORX_C2')
                        ltflt=22;
                    else
                        ltflt=11;
                    end
                    if ~isempty(idx)
                        data{idx} = ['LTFLTI = ' sprintf('%1.1f',ltflt) ' $$'];
                    end
                        
                    %         idx = find(startsWith(data,'C'));
                    %         data(idx,:)=[]; %remove comments
                    %         idx = find(startsWith(data,'--'));
                    %         data(idx,:)=[]; %remove comments from MODELS

                    %         outfile='/home/amauri/Documents/_ProjCEPEL/BasesKleber/AT/ArquivosATP/teste.atp';
                    head{1,1}='C ------------------------------------------------------------------------------';
                    head{end+1,1}='C Case file generated using AutoATPCaseGen';
                    head{end+1,1}='C AutoATPCaseGen developed by A. G. Martins-Britto at UnB - Brazil - 2022';
                    head{end+1,1}='C github.com/amaurigmartins';
                    head{end+1,1}='C ';
                    head{end+1,1}=sprintf('C JobID: %s',app.JobIDEditField.Value);
                    if ~isempty(app.ProjectdescriptionTextArea.Value{1})
                        head{end+1,1}=sprintf('C Project description:');
                        head{end+1,1}=sprintf('C -------------------');
                        for k=1:size(app.ProjectdescriptionTextArea.Value,1)
                            if ~isempty(app.ProjectdescriptionTextArea.Value{k})
                                head{end+1,1}=sprintf('C %s',app.ProjectdescriptionTextArea.Value{k});
                            end
                        end
                    end
                    head{end+1,1}='C ------------------------------------------------------------------------------';

                    idx = find(contains(data,'C  dT  >< Tmax >< Xopt >< Copt ><Epsiln>'));
                    dT=app.dTsEditField.Value;
                    Tmax=app.TmaxsEditField.Value;
                    Copt=app.CoptEditField.Value;
                    Xopt=app.XoptEditField.Value;
                    tmp_str=blanks(80);

                    tmp_str(1:8)= sprintf('% 8.8G',dT);
                    tmp_str(9:16)=sprintf('% 8.8G',Tmax);
                    tmp_str(17:24)=sprintf('% 8.8G',Xopt);
                    tmp_str(25:32)=sprintf('% 8.8G',Copt);
                    data{idx+1}=tmp_str;

                    data=[head; data];
                    out=strjoin(data, '\n') ;%para escrever o arquivo
                    out=strrep(out,'\','\\');
                    out=strrep(out,'%','%%');
                    %         out = regexprep(out, {'\r', '\n\n+'}, {'', '\n'});
                    %         out=strcat(out,'\n');
                    outfile = cellfun(@num2str,allcases_data(j,:),'UniformOutput',false);
                    outfile = strjoin(outfile,'_');
                    matexp = sprintf('c%d_%s_casedata.mat',j,outfile);
                    outfile = sprintf('c%d_%s.atp',j,outfile);
                    filelist{j}=outfile;


                    fid = fopen(fullfile(workdir,jobid,outfile),'wt');
                    fprintf(fid, out);
                    fclose(fid);

                    save(fullfile(workdir,jobid,matexp),'pl4bin','workdir',...
                        'jobid','outfile','vars','thiscasedata','fmodel',...
                        'linecir','templatedir','caseid','purgepl4','keepmatfiles');

                end
                app.ProgrammessagesTextArea.Value{end+1} = ['Building cases index...'];
                C = [filelist' allcases_data];
                T = cell2table(C, "VariableNames",["Filename" [vars.param]]);
                indexfname = fullfile(workdir,jobid,'CasesIndex.xlsx');
                writetable(T,indexfname);
                app.ProgrammessagesTextArea.Value{end+1} = ['All done!'];


            catch
                msg='Unable to write output file!';
                f = msgbox(msg,'You lose, fella.','error');
                app.ProgrammessagesTextArea.Value{end+1} = ['Something went wrong (and no, I am not very good with making descriptive messages).'];
                app.ProgrammessagesTextArea.Value{end+1} = ['According to some experts, it''s very likely that you tried to use a $PARAMETER name that does not exist in the original ATP file.'];

                try;fclose(fid);catch;end;
                return;
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.MiscTab.Parent=[];
            app.JobIDEditField.Editable=0;
            app.EditCheckBox.Value=0;
            app.JobIDEditField.Value=strcat('XX__',app.FaultmodelDropDown.Value,'_',app.LineCircuitDropDown.Value,'_',app.CaseDropDown.Value);
            app.EditCheckBox_Inc.Value=0;
            app.INCLUDEINSERTTextArea.Editable=0;
            orig_state = warning;warning('off','all');
            currentFolder = pwd;
            app.WorkdirectoryEditField.Value=pwd;
            addpath(fullfile(currentFolder,'functions'));
            warning(orig_state);
            t=table;
            t.PARAM=strings(0);
            t.Type=strings(0);
            t.CurrentValue=strings(0);
            t.NewValues=strings(0);
            %             t.Step=zeros(0);
            %             t.Max=zeros(0);
            %             t.Preffix=strings(0);

            t.Type = categorical(t.Type,{'numeric','string'}, ...
                'Ordinal',true);

            app.UITable.Data=t;
            app.UITable.ColumnEditable = [true true true true];
            app.UITable.ColumnWidth = {115,60,100,'auto'};

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)

            [file,path] = uigetfile({'*.atp'});
            app.AutoATPCaseGen.Visible = 'off';
            app.AutoATPCaseGen.Visible = 'on';
            if file ~=0
                fname=fullfile(path,file);
                app.TemplateATPfileatpEditField.Value=fname;
                Load2TableButtonPushed(app, event);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            nonechecked=true;
            jobid=app.JobIDEditField.Value;
            infile=app.TemplateATPfileatpEditField.Value;
            workdir=app.WorkdirectoryEditField.Value;
            solvercall=app.ATPsolverEditField.Value;
            pl42matcall=app.PL42MATEditField.Value;
            matlabcall=app.MATLABbinaryEditField.Value;

            try
                clc;
                if app.BuildcasefilesCheckBox.Value == 1
                    nonechecked=false;
                    if ~isempty(app.UITable.Data)
                        [vars, vartable] = buildCaseData(app);
                        if isempty(app.ProgrammessagesTextArea.Value{1})
                            app.ProgrammessagesTextArea.Value{1} = ['****** Process started.'];
                        else
                            app.ProgrammessagesTextArea.Value{end+1} = ['****** Process started.'];
                        end
                        orig_state = warning;warning('off','all');
                        if isfolder(fullfile(workdir,jobid))
                            rmdir(fullfile(workdir,jobid), 's');
                        end
                        mkdir(fullfile(workdir,jobid));
                        warning(orig_state);

                        if ~isempty(app.INCLUDEINSERTTextArea.Value{1})
                            app.ProgrammessagesTextArea.Value{end+1} = ['Copying linked files...'];
                            [filepath,~,~] = fileparts(app.TemplateATPfileatpEditField.Value);
                            for k=1:size(app.INCLUDEINSERTTextArea.Value,1)
                                %                                 fname=app.INCLUDEINSERTTextArea.Value{k};
                                [~,fname,fext]=fileparts(app.INCLUDEINSERTTextArea.Value{k});
                                fname=strcat(fname,fext);
                                copyfile(fullfile(filepath,fname),fullfile(workdir,jobid));
                                app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Copy: %s - done!',fname)];
                            end
                        end
                        writeATPCaseFiles(app,infile,jobid,vars,vartable);
                    else
                        f = msgbox('No parameters informed. Why not using ATPDraw instead?', 'You lose, fella.','error');
                    end
                end

                if app.RunATPsolverCheckBox.Value == 1
                    nonechecked=false;
                    wdir=fullfile(workdir,jobid);

                    if ~isfile(solvercall)
                        f = msgbox('ATP solver file not found!','You lose, fella.','error');
                        return
                    end

                    if app.PostprocessfilesCheckBox.Value==1
                        if app.ConverttoMATLABCheckBox.Value==1
                            if ~isfile(pl42matcall)
                                f = msgbox('PL42MAT binary not found!','You lose, fella.','error');
                                return
                            end
                        end
                        if app.ConverttoCOMTRADECheckBox.Value==1
                            if ~isfile(pl42matcall)
                                f = msgbox('PL42MAT binary not found!','You lose, fella.','error');
                                return
                            end
                            if ~isfile(matlabcall)
                                f = msgbox('MATLAB binary not found!','You lose, fella.','error');
                                return
                            end
                        end
                    end


                    dirdata=dir(fullfile(wdir,'*.atp'));
                    if isempty(dirdata)
                        f = msgbox('No ATP files found in the work directory!','You lose, fella.','error');
                        return
                    end
                    if app.CommandlineButton.Value ==1
                        answer = questdlg('Before proceeding with this action, verify that ATP Launcher is properly configured: field ''Close window at end'' must be CHECKED, fields ''Output to screen'' and ''Use work directory'' must be UNCHECKED. Confirm: ', ...
                            'Confirm?', ...
                            'Yes','No','Yes');
                        if strcmp(answer,'No')
                            return;
                        end

                        app.ProgrammessagesTextArea.Value{end+1} = ['****** Running ATP solver...'];
                        app.ProgrammessagesTextArea.Value{end+1} = ['(use MATLAB Command Window to monitor progress)'];
                        runAllCases(app,wdir,dirdata,solvercall);
                    elseif app.BuildbatfileButton.Value ==1
                        app.ProgrammessagesTextArea.Value{end+1} = ['****** Building .bat file...'];
                        outfile='runAllCases.bat';
                        fid = fopen(fullfile(workdir,jobid,outfile),'wt');
                        out = '@ECHO OFF \n';
                        out = [out sprintf('@cd %s',strrep(fullfile(workdir,jobid),'\','\\')) '\n'];
                        for k=1:length(dirdata)
                            fname=dirdata(k).name;
                            [~,fstr,~]=fileparts(fname);
                            cmd=['"' strrep(solvercall,'\','\\') '" ' '"' strrep(fullfile(wdir,fname),'\','\\') '"'];
                            out = [out sprintf('@ECHO ---------- Running case %d, file: %s >> runAllCases.log \n',k,fstr)];
                            out = [out sprintf('@ECHO Started: %%%%date%%%% %%%%time%%%% >> runAllCases.log \n')];
                            out = [out cmd '\n'];
                            out = [out sprintf('@ECHO Completed: %%%%date%%%% %%%%time%%%% >> runAllCases.log \n')];
                            out = [out sprintf('@if exist MODELS.1 RENAME MODELS.1 %s.1',fstr) '\n'];
                            out = [out sprintf('@if exist MODELS.2 RENAME MODELS.2 %s.2',fstr) '\n'];
                            if app.PostprocessfilesCheckBox.Value==1
                                if app.ConverttoMATLABCheckBox.Value==1
                                    cmd=['"' strrep(pl42matcall,'\','\\') '" ' '"' strrep(fullfile(wdir,[fstr '.pl4']),'\','\\') '"'];
                                    out = [out cmd '\n'];
                                end
                                if app.ConverttoCOMTRADECheckBox.Value==1
                                    cmd=['"' strrep(matlabcall,'\','\\') '" -nosplash -nodesktop -r ' '"cd(''' strrep(fullfile(wdir),'\','\\') ''');addpath(''' strrep(fullfile(workdir,'functions'),'\','\\') ''');' ...
                                        'pl42comtrade(''' fstr ''');exit;"'];
                                    out = [out cmd '\n'];
                                end
                            end

                            %                             if app.CleanupPL4filesCheckBox.Value
                            %                                 out = [out sprintf('@if exist %s.pl4 del %s.pl4',fstr, fstr) '\n'];
                            %                             end
                            out = [out sprintf('@RENAME %s %s.done',fname,fstr) '\n'];
                        end
                        out = [out '@if exist *.bin del *.bin' '\n'];
                        out = [out '@if exist *.tmp del *.tmp' '\n'];
                        out = [out '@if exist *.dbg del *.dbg' '\n'];
                        fprintf(fid, out);
                        fclose(fid);

                        outfile='resetRunQueue.bat';
                        fid = fopen(fullfile(workdir,jobid,outfile),'wt');
                        out = '@ECHO OFF \n';
                        out = [out '@rename *.done *.atp' '\n'];
                        out = [out '@del runAllCases.log' '\n'];
                        fprintf(fid, out);
                        fclose(fid);
                    end


                    app.ProgrammessagesTextArea.Value{end+1} = ['All done!'];

                end

                if app.PostprocessfilesCheckBox.Value == 1
                    nonechecked=false;
                end

            catch
                f = msgbox('Error running AutoATPCaseGen!','You lose, fella.','error');
            end
            if nonechecked
                f = msgbox('No task chosen!', 'You lose, fella.','error');
            end
        end

        % Button pushed function: Load2TableButton
        function Load2TableButtonPushed(app, event)
            [testfile,msg]=validateFilename(app);
            if ~testfile
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            fname=app.TemplateATPfileatpEditField.Value;

            [fdata,msg]=loadFromFile(app,fname);

            if isempty(fdata)
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            else
                app.dTsEditField.Value=str2double(fdata.dT);
                app.CoptEditField.Value=str2double(fdata.Copt);
                app.XoptEditField.Value=str2double(fdata.Xopt);
                app.TmaxsEditField.Value=str2double(fdata.Tmax);
                nrows=size(fdata.params,1);
                app.INCLUDEINSERTTextArea.Value=fdata.include;
                app.UITable.Data=[app.UITable.Data;fdata.params];
                %                 app.AutoATPCaseGen.Visible = 'off';
                %                 app.AutoATPCaseGen.Visible = 'on';
                f = msgbox(sprintf('Found %d valid $PARAMETERS!',nrows), 'Success','help','modal');
                redefRowLabels(app)


            end

        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            newrow={'VAR','numeric',9999,9999};
            app.UITable.Data=[app.UITable.Data;newrow];
            redefRowLabels(app)


        end

        % Button pushed function: DeleterowButton
        function DeleterowButtonPushed(app, event)
            delrow=str2double(app.row2Delete.Value);
            if ~isempty(app.UITable.Data)
                if isnan(delrow)
                    app.UITable.Data(end,:)=[];
                else
                    app.UITable.Data(delrow,:)=[];
                end
                redefRowLabels(app)
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if ~isempty(app.UITable.Data)
                indices = event.Indices;
                if ~isempty(indices)
                    selectedRow = indices(1);
                    app.row2Delete.Value=string(selectedRow);
                end

            end
        end

        % Button pushed function: SaveinputsessionButton
        function SaveinputsessionButtonPushed(app, event)
            saveAllData(app);
        end

        % Button pushed function: LoadinputsessionButton
        function LoadinputsessionButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            app.AutoATPCaseGen.Visible = 'off';
            app.AutoATPCaseGen.Visible = 'on';
            if file ~= 0
                fname=fullfile(path,file);
                try
                    load(fname,'atpcasegen_data');
                    writeAllAppValues(app,atpcasegen_data);
                    %                     setColFormats(app);
                catch
                    f = msgbox('Unable to load project file!','You lose, fella.','error');
                    return;
                end
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(1,2)==4
                try
                    test=eval(newData);
                catch
                    f = msgbox('Invalid data or MATLAB operator.','You lose, fella.','error');
                    return;
                end
            end
        end

        % Button pushed function: LoadATPButton
        function LoadATPButtonPushed(app, event)
            [file,path] = uigetfile({'*.exe';'*.bat'});
            app.AutoATPCaseGen.Visible = 'off';
            app.AutoATPCaseGen.Visible = 'on';
            if path ~=0
                app.ATPsolverEditField.Value=fullfile(path,file);
            end
        end

        % Button pushed function: LoadWorkdirButton
        function LoadWorkdirButtonPushed(app, event)
            path=uigetdir(pwd,'Choose work directory');
            app.AutoATPCaseGen.Visible = 'off';
            app.AutoATPCaseGen.Visible = 'on';
            if path ~=0
                app.WorkdirectoryEditField.Value=fullfile(path);
            end
        end

        % Button pushed function: LaunchdebugButton
        function LaunchdebugButtonPushed(app, event)
            debugme
        end

        % Button pushed function: ClearmessagesButton
        function ClearmessagesButtonPushed(app, event)
            app.ProgrammessagesTextArea.Value='';
        end

        % Button pushed function: LoadMATLABButton
        function LoadMATLABButtonPushed(app, event)
            [file,path] = uigetfile({'*.exe'});
            app.AutoATPCaseGen.Visible = 'off';
            app.AutoATPCaseGen.Visible = 'on';
            if path ~=0
                app.MATLABbinaryEditField.Value=fullfile(path,file);
            end
        end

        % Button pushed function: LoadPl42matButton
        function LoadPl42matButtonPushed(app, event)
            [file,path] = uigetfile({'*.exe'});
            app.AutoATPCaseGen.Visible = 'off';
            app.AutoATPCaseGen.Visible = 'on';
            if path ~=0
                app.PL42MATEditField.Value=fullfile(path,file);
            end
        end

        % Value changed function: EditCheckBox
        function EditCheckBoxValueChanged(app, event)
            value = app.EditCheckBox.Value;
            app.JobIDEditField.Editable=value;
        end

        % Value changed function: FaultmodelDropDown
        function FaultmodelDropDownValueChanged(app, event)
            value = app.FaultmodelDropDown.Value;
            app.JobIDEditField.Value=strcat('XX__',app.FaultmodelDropDown.Value,'_',app.LineCircuitDropDown.Value,'_',app.CaseDropDown.Value);
        end

        % Value changed function: LineCircuitDropDown
        function LineCircuitDropDownValueChanged(app, event)
            value = app.LineCircuitDropDown.Value;
            app.JobIDEditField.Value=strcat('XX__',app.FaultmodelDropDown.Value,'_',app.LineCircuitDropDown.Value,'_',app.CaseDropDown.Value);
        end

        % Value changed function: CaseDropDown
        function CaseDropDownValueChanged(app, event)
            value = app.CaseDropDown.Value;
            app.JobIDEditField.Value=strcat('XX__',app.FaultmodelDropDown.Value,'_',app.LineCircuitDropDown.Value,'_',app.CaseDropDown.Value);
        end

        % Value changed function: EditCheckBox_Inc
        function EditCheckBox_IncValueChanged(app, event)
            value = app.EditCheckBox_Inc.Value;
            app.INCLUDEINSERTTextArea.Editable=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutoATPCaseGen = uifigure('Visible', 'off');
            app.AutoATPCaseGen.Name = 'AutoEvoltzCaseGen - Helper tool for batch ATP simulations';
            app.TabGroup = uitabgroup(app.AutoATPCaseGen);
            app.CasedataTab = uitab(app.TabGroup);
            app.CasedataTab.Title = 'Case data';
            app.LoadFileButton = uibutton(app.CasedataTab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = '📂';
            app.TemplateATPfileatpEditFieldLabel = uilabel(app.CasedataTab);
            app.TemplateATPfileatpEditFieldLabel.Text = 'Template ATP file [.atp]:';
            app.TemplateATPfileatpEditField = uieditfield(app.CasedataTab, 'text');
            app.TemplateATPfileatpEditField.Editable = 'off';
            app.UITable = uitable(app.CasedataTab);
            app.UITable.ColumnName = {'$PARAMETER'; 'Type'; 'CurrentValue'; 'NewValues'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Load2TableButton = uibutton(app.CasedataTab, 'push');
            app.Load2TableButton.ButtonPushedFcn = createCallbackFcn(app, @Load2TableButtonPushed, true);
            app.Load2TableButton.Text = '🔧 Parse ATP file';
            app.AddrowButton = uibutton(app.CasedataTab, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Text = '➕ Add row';
            app.DeleterowButton = uibutton(app.CasedataTab, 'push');
            app.DeleterowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterowButtonPushed, true);
            app.DeleterowButton.Text = '🗙 Delete row:';
            app.JobIDEditFieldLabel = uilabel(app.CasedataTab);
            app.JobIDEditFieldLabel.Text = 'JobID:';
            app.JobIDEditField = uieditfield(app.CasedataTab, 'text');
            app.SaveinputsessionButton = uibutton(app.CasedataTab, 'push');
            app.SaveinputsessionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinputsessionButtonPushed, true);
            app.SaveinputsessionButton.Text = '🖪 Save input session';
            app.ProjectdescriptionTextAreaLabel = uilabel(app.CasedataTab);
            app.ProjectdescriptionTextAreaLabel.Text = 'Project description:';
            app.ProjectdescriptionTextArea = uitextarea(app.CasedataTab);
            app.row2Delete = uieditfield(app.CasedataTab, 'text');
            app.LoadinputsessionButton = uibutton(app.CasedataTab, 'push');
            app.LoadinputsessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadinputsessionButtonPushed, true);
            app.LoadinputsessionButton.Text = '📁 Load input session';
            app.CoptEditFieldLabel = uilabel(app.CasedataTab);
            app.CoptEditFieldLabel.Text = 'Copt';
            app.CoptEditField = uieditfield(app.CasedataTab, 'numeric');
            app.CoptEditField.Limits = [0 Inf];
            app.CoptEditField.Value = 60;
            app.dTsEditFieldLabel = uilabel(app.CasedataTab);
            app.dTsEditFieldLabel.Text = 'dT [s]:';
            app.dTsEditField = uieditfield(app.CasedataTab, 'numeric');
            app.dTsEditField.Limits = [0 Inf];
            app.dTsEditField.Value = 1e-06;
            app.TmaxsEditFieldLabel = uilabel(app.CasedataTab);
            app.TmaxsEditFieldLabel.Text = 'Tmax [s]:';
            app.TmaxsEditField = uieditfield(app.CasedataTab, 'numeric');
            app.TmaxsEditField.Limits = [0 100];
            app.TmaxsEditField.Value = 0.1;
            app.INCLUDEINSERTTextAreaLabel = uilabel(app.CasedataTab);
            app.INCLUDEINSERTTextAreaLabel.Text = '$INCLUDE / $INSERT';
            app.INCLUDEINSERTTextArea = uitextarea(app.CasedataTab);
            app.INCLUDEINSERTTextArea.Editable = 'off';
            app.ExitButton = uibutton(app.CasedataTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '🚪 Exit';
            app.XoptEditFieldLabel = uilabel(app.CasedataTab);
            app.XoptEditFieldLabel.Text = 'Xopt';
            app.XoptEditField = uieditfield(app.CasedataTab, 'numeric');
            app.XoptEditField.Limits = [0 Inf];
            app.XoptEditField.Value = 60;
            app.FaultmodelLabel = uilabel(app.CasedataTab);
            app.FaultmodelLabel.Text = 'Fault model:';
            app.FaultmodelDropDown = uidropdown(app.CasedataTab);
            app.FaultmodelDropDown.Items = {'FTR', 'FAI', 'FIN'};
            app.FaultmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @FaultmodelDropDownValueChanged, true);
            app.FaultmodelDropDown.Value = 'FTR';
            app.LineCircuitLabel = uilabel(app.CasedataTab);
            app.LineCircuitLabel.Text = 'Line/Circuit:';
            app.LineCircuitDropDown = uidropdown(app.CasedataTab);
            app.LineCircuitDropDown.Items = {'LEC_SIL_C1', 'LEC_SIL_C2', 'SIL_ORX_C1', 'SIL_ORX_C2', 'HVDC_P1', 'HVDC_P2'};
            app.LineCircuitDropDown.ValueChangedFcn = createCallbackFcn(app, @LineCircuitDropDownValueChanged, true);
            app.LineCircuitDropDown.Value = 'LEC_SIL_C1';
            app.EditCheckBox = uicheckbox(app.CasedataTab);
            app.EditCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditCheckBoxValueChanged, true);
            app.EditCheckBox.Text = 'Edit';
            app.CaseLabel = uilabel(app.CasedataTab);
            app.CaseLabel.Text = 'Case:';
            app.CaseDropDown = uidropdown(app.CasedataTab);
            app.CaseDropDown.Items = {'LEVE', 'PESADA', 'HVDC'};
            app.CaseDropDown.ValueChangedFcn = createCallbackFcn(app, @CaseDropDownValueChanged, true);
            app.CaseDropDown.Value = 'LEVE';
            app.EditCheckBox_Inc = uicheckbox(app.CasedataTab);
            app.EditCheckBox_Inc.ValueChangedFcn = createCallbackFcn(app, @EditCheckBox_IncValueChanged, true);
            app.EditCheckBox_Inc.Text = 'Edit';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.SolvercallButtonGroup = uibuttongroup(app.RunTab);
            app.SolvercallButtonGroup.Title = 'Solver call';
            app.CommandlineButton = uiradiobutton(app.SolvercallButtonGroup);
            app.CommandlineButton.Text = 'Command line';
            app.CommandlineButton.Value = true;
            app.BuildbatfileButton = uiradiobutton(app.SolvercallButtonGroup);
            app.BuildbatfileButton.Text = 'Build .bat file';
            app.TasksButtonGroup = uibuttongroup(app.RunTab);
            app.TasksButtonGroup.Title = 'Tasks';
            app.BuildcasefilesCheckBox = uicheckbox(app.TasksButtonGroup);
            app.BuildcasefilesCheckBox.Text = 'Build case files';
            app.BuildcasefilesCheckBox.Value = true;
            app.RunATPsolverCheckBox = uicheckbox(app.TasksButtonGroup);
            app.RunATPsolverCheckBox.Text = 'Run ATP solver';
            app.RunATPsolverCheckBox.Value = true;
            app.PostprocessfilesCheckBox = uicheckbox(app.TasksButtonGroup);
            app.PostprocessfilesCheckBox.Text = 'Post-process files';
            app.PostprocessfilesCheckBox.Value = true;
            app.UploadtoremoteCheckBox = uicheckbox(app.TasksButtonGroup);
            app.UploadtoremoteCheckBox.Enable = 'off';
            app.UploadtoremoteCheckBox.Text = 'Upload to remote';
            app.ProgrammessagesTextAreaLabel = uilabel(app.RunTab);
            app.ProgrammessagesTextAreaLabel.Text = 'Program messages:';
            app.ProgrammessagesTextArea = uitextarea(app.RunTab);
            app.ProgrammessagesTextArea.Editable = 'off';
            app.ClearmessagesButton = uibutton(app.RunTab, 'push');
            app.ClearmessagesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearmessagesButtonPushed, true);
            app.ClearmessagesButton.Text = '🗑️ Clear messages';
            app.StartButton = uibutton(app.RunTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = '▶️ Start!';
            app.UseWineLinuxCheckBox = uicheckbox(app.RunTab);
            app.UseWineLinuxCheckBox.Text = 'Use Wine (Linux)';
            app.LaunchdebugButton = uibutton(app.RunTab, 'push');
            app.LaunchdebugButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchdebugButtonPushed, true);
            app.LaunchdebugButton.Text = '🧪 Launch debug ';
            app.MiscTab = uitab(app.TabGroup);
            app.MiscTab.Title = 'Misc';
            app.PostprocessingButtonGroup = uibuttongroup(app.MiscTab);
            app.PostprocessingButtonGroup.Title = 'Post-processing';
            app.CleanupPL4filesCheckBox = uicheckbox(app.PostprocessingButtonGroup);
            app.CleanupPL4filesCheckBox.Text = 'Clean up PL4 files';
            app.CleanupPL4filesCheckBox.Value = true;
            app.ConverttoMATLABCheckBox = uicheckbox(app.PostprocessingButtonGroup);
            app.ConverttoMATLABCheckBox.Text = 'Convert to MATLAB';
            app.ConverttoCOMTRADECheckBox = uicheckbox(app.PostprocessingButtonGroup);
            app.ConverttoCOMTRADECheckBox.Text = 'Convert to COMTRADE';
            app.ConverttoCOMTRADECheckBox.Value = true;
            app.PathstoexecutablesandoutputfolderPanel = uipanel(app.MiscTab);
            app.PathstoexecutablesandoutputfolderPanel.Title = 'Paths to executables and output folder';
            app.LoadATPButton = uibutton(app.PathstoexecutablesandoutputfolderPanel, 'push');
            app.LoadATPButton.ButtonPushedFcn = createCallbackFcn(app, @LoadATPButtonPushed, true);
            app.LoadATPButton.Text = '📂';
            app.ATPsolverEditFieldLabel = uilabel(app.PathstoexecutablesandoutputfolderPanel);
            app.ATPsolverEditFieldLabel.Text = 'ATP solver:';
            app.ATPsolverEditField = uieditfield(app.PathstoexecutablesandoutputfolderPanel, 'text');
            app.ATPsolverEditField.Value = '%ATPPATH%\tools\runATP.exe';
            app.LoadWorkdirButton = uibutton(app.PathstoexecutablesandoutputfolderPanel, 'push');
            app.LoadWorkdirButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWorkdirButtonPushed, true);
            app.LoadWorkdirButton.Text = '📂';
            app.WorkdirectoryEditFieldLabel = uilabel(app.PathstoexecutablesandoutputfolderPanel);
            app.WorkdirectoryEditFieldLabel.Text = 'Work directory:';
            app.WorkdirectoryEditField = uieditfield(app.PathstoexecutablesandoutputfolderPanel, 'text');
            app.LoadPl42matButton = uibutton(app.PathstoexecutablesandoutputfolderPanel, 'push');
            app.LoadPl42matButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPl42matButtonPushed, true);
            app.LoadPl42matButton.Text = '📂';
            app.PL42MATEditFieldLabel = uilabel(app.PathstoexecutablesandoutputfolderPanel);
            app.PL42MATEditFieldLabel.Text = 'PL42MAT:';
            app.PL42MATEditField = uieditfield(app.PathstoexecutablesandoutputfolderPanel, 'text');
            app.PL42MATEditField.Value = '%WORKDIR%\tools\Pl42mat.exe';
            app.LoadMATLABButton = uibutton(app.PathstoexecutablesandoutputfolderPanel, 'push');
            app.LoadMATLABButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMATLABButtonPushed, true);
            app.LoadMATLABButton.Text = '📂';
            app.MATLABbinaryEditFieldLabel = uilabel(app.PathstoexecutablesandoutputfolderPanel);
            app.MATLABbinaryEditFieldLabel.Text = 'MATLAB binary:';
            app.MATLABbinaryEditField = uieditfield(app.PathstoexecutablesandoutputfolderPanel, 'text');
            app.MATLABbinaryEditField.Value = '%MATLABROOTDIR%\bin\win64\MATLAB.exe';
            app.COMTRADEoutputButtonGroup = uibuttongroup(app.MiscTab);
            app.COMTRADEoutputButtonGroup.Title = 'COMTRADE output';
            app.UseRDPfiltersCheckBox = uicheckbox(app.COMTRADEoutputButtonGroup);
            app.UseRDPfiltersCheckBox.Enable = 'off';
            app.UseRDPfiltersCheckBox.Text = 'Use RDP filters';
            app.UseRDPfiltersCheckBox.Value = true;
            app.UseTWfiltersCheckBox = uicheckbox(app.COMTRADEoutputButtonGroup);
            app.UseTWfiltersCheckBox.Enable = 'off';
            app.UseTWfiltersCheckBox.Text = 'Use TW filters';
            app.UseTWfiltersCheckBox.Value = true;
            app.UploadtoremoteButtonGroup = uibuttongroup(app.MiscTab);
            app.UploadtoremoteButtonGroup.Title = 'Upload to remote';
            app.FTPserverEditFieldLabel = uilabel(app.UploadtoremoteButtonGroup);
            app.FTPserverEditFieldLabel.Text = 'FTP server:';
            app.FTPserverEditField = uieditfield(app.UploadtoremoteButtonGroup, 'text');
            app.FTPserverEditField.Enable = 'off';
            app.LoginEditFieldLabel = uilabel(app.UploadtoremoteButtonGroup);
            app.LoginEditFieldLabel.Text = 'Login:';
            app.LoginEditField = uieditfield(app.UploadtoremoteButtonGroup, 'text');
            app.LoginEditField.Enable = 'off';
            app.PasswordEditFieldLabel = uilabel(app.UploadtoremoteButtonGroup);
            app.PasswordEditFieldLabel.Text = 'Password:';
            app.PasswordEditField = uieditfield(app.UploadtoremoteButtonGroup, 'text');
            app.PasswordEditField.Enable = 'off';
            app.PortEditFieldLabel = uilabel(app.UploadtoremoteButtonGroup);
            app.PortEditFieldLabel.Text = 'Port:';
            app.PortEditField = uieditfield(app.UploadtoremoteButtonGroup, 'text');
            app.PortEditField.Enable = 'off';
            app.RemotedirectoryEditFieldLabel = uilabel(app.UploadtoremoteButtonGroup);
            app.RemotedirectoryEditFieldLabel.Text = 'Remote directory:';
            app.RemotedirectoryEditField = uieditfield(app.UploadtoremoteButtonGroup, 'text');
            app.RemotedirectoryEditField.Enable = 'off';
            app.UploadnowButton = uibutton(app.UploadtoremoteButtonGroup, 'push');
            app.UploadnowButton.Enable = 'off';
            app.UploadnowButton.Text = '📤 Upload now';
            app.FilesinWORKDIRTextAreaLabel = uilabel(app.UploadtoremoteButtonGroup);
            app.FilesinWORKDIRTextAreaLabel.Text = 'Files in %WORKDIR%';
            app.FilesinWORKDIRTextArea = uitextarea(app.UploadtoremoteButtonGroup);
            app.FilesinWORKDIRTextArea.Enable = 'off';
            app.FilesinWORKDIRTextArea.Value = {'*.dat; *.cfg'};
            % Show the figure after all components are created
            app.AutoATPCaseGen.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main2021

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutoATPCaseGen)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutoATPCaseGen)
        end
    end
end